classdef interface_2down1up < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure                   % CI Psy...
        ScenarioconfigurationPanel matlab.ui.container.Panel % Scenario con...
        noisetypeButtonGroup    matlab.ui.container.ButtonGroup    % noise ...
        babble                  matlab.ui.control.RadioButton      % babble
        ICRA                    matlab.ui.control.RadioButton      % ICRA
        SSN_IEEE                matlab.ui.control.RadioButton      % SSN_IEEE
        GainButtonGroup         matlab.ui.container.ButtonGroup    % NR Tec...
        MMSEButton              matlab.ui.control.RadioButton      % Envelo...
        WienerButton            matlab.ui.control.RadioButton      % Wiener...
        BinaryMaskButton        matlab.ui.control.RadioButton      % Binary...
        UnprocessedButton       matlab.ui.control.RadioButton      % Unproc...
        AdaptativeintelligibilitytestprotocolPanel matlab.ui.container.Panel
        AdaptativetestprogressPanel matlab.ui.container.Panel % Adaptative ...
        SNRLabel                matlab.ui.control.Label            % SNR: 
        valueSNR                matlab.ui.control.Label            % ------
        AudiosentenceLabel      matlab.ui.control.Label            % Audio ...
        sentence                matlab.ui.control.Label            % ------
        CicletwodownoneupLabel  matlab.ui.control.Label            % Cicle ...
        numberCicle             matlab.ui.control.Label            % ------
        NumberAudio             matlab.ui.control.Label            % ------
        audioLabel              matlab.ui.control.Label            % # of c...
        DatarepresentationPanel matlab.ui.container.Panel          % Data r...
        udioemexecuoTextAreaLabel matlab.ui.control.Label % Sentence 
        fraseExecucao           matlab.ui.control.TextArea        
        StatusdereproduoLampLabel matlab.ui.control.Label % Status:
        StatusdereproduoLamp    matlab.ui.control.Lamp            
        VerdeudioexecutandoVermelhoOuvintepronunciandoAzulLabel matlab.ui.control.Label
        p1Button_1              matlab.ui.control.StateButton      % p1
        p1Button_2              matlab.ui.control.StateButton      % p1
        p1Button_3              matlab.ui.control.StateButton      % p1
        p1Button_4              matlab.ui.control.StateButton      % p1
        p1Button_5              matlab.ui.control.StateButton      % p1
        p1Button_6              matlab.ui.control.StateButton      % p1
        p1Button_7              matlab.ui.control.StateButton      % p1
        p1Button_8              matlab.ui.control.StateButton      % p1
        p1Button_9              matlab.ui.control.StateButton      % p1
        Panel                   matlab.ui.container.Panel         
        TotalwordscorrectLabel  matlab.ui.control.Label            % Correc...
        palavrasCorretas        matlab.ui.control.NumericEditField % [-Inf ...
        NmerototaldepalavrasEditFieldLabel matlab.ui.control.Label % Total ...
        totalPalavras           matlab.ui.control.NumericEditField % [-Inf ...
        NextButton              matlab.ui.control.StateButton      % Next
        ComputeButton           matlab.ui.control.Button           % Compute
        p1Button_10             matlab.ui.control.StateButton      % p1
        p1Button_11             matlab.ui.control.StateButton      % p1
        repeatLastAudio         matlab.ui.control.StateButton      % Repeat
        selectAll               matlab.ui.control.Button           % All co...
        selectNone              matlab.ui.control.Button           % None c...
        plotFlag                matlab.ui.control.CheckBox         % Plot E...
        Push2TalkButton         matlab.ui.control.StateButton      % Push t...
        ControlpanelPanel       matlab.ui.container.Panel          % Contro...
        StartButton             matlab.ui.control.StateButton      % Start
        StopButton              matlab.ui.control.StateButton      % Stop
        PanelChecklist          matlab.ui.container.Panel          % Pre-te...
        checklistOKButton       matlab.ui.control.StateButton      % OK
        CheckBoxTerm            matlab.ui.control.CheckBox         % Term o...
        CheckBoxExplain         matlab.ui.control.CheckBox         % Explai...
        CheckBoxVolume          matlab.ui.control.CheckBox         % Adjust...
        CheckBoxScenario        matlab.ui.control.CheckBox         % Verify...
        CheckBoxLoad            matlab.ui.control.CheckBox         % Load map
        Panel2                  matlab.ui.container.Panel          % Gain C...
        LabelKnob               matlab.ui.control.Label            % Master...
        KnobGain                matlab.ui.control.Knob             % [0 60]
        ButtonPlayTest          matlab.ui.control.Button           % Play n...
    end

    
    properties (Access = private)
        stimuli              % Description
        SNRStep             % Description
        flagCounterCicles       % Description
        snr_value               % Description
        earref                  % Description
        AZN                     % Description
        correctRate             % Description
        sentenceWords           % Description
        FolderFullPath          % Description
        fileName                % Description
        correctWords            % Description
        Results                 % Description
        NumTotalWords           % Description
        audioExec               % Description
        wordsAll                % Description
        snr_vecValues           % Description
        MF                      % Description
        folder1                 % Description
        audioN                  % Description
        previousAudio           % Description
        flag2down             % Description
        flagChooseOtherGain     % Description
        channelOutput           % Description
        flagVSameAudio          % Description
        flagAudioRepeated       % Description
        diff_old                % old derivative sign
        testedGains             % Description
        gainscount = 0;         % Description
        speechList              % list of speech files
        map                     % patient map
        map_int                 % patient map for real-time stimulation (integer ppf)
    end
    
    methods (Access = private)
        
        function clearWordsButtons(app)
            % Clear sentence words buttons
            for iWord=1:11
                eval(sprintf('app.p1Button_%.0f.Visible = ''off'';',iWord))
                eval(sprintf('app.p1Button_%.0f.Value = 0;',iWord))
            end
        end
             
        
        function getNewAudio(app)
            % Randomly get new audio from Alcaim1_
            
            while(sum(strcmp(app.previousAudio, app.audioN)))
                %%randomly pick a speech file
                L = length(app.speechList); %number of speech filesin the database
                idx = randi([1,L],1); %index of the randomly chosen file
                [fpath, fname] = fileparts(app.speechList{idx});
                app.audioN = fname;
                app.FolderFullPath = fpath;
            end            
            
            %store selected audio to history of played audios
            app.previousAudio{app.audioExec,1} = app.audioN;
            
            %update file sentence name
            app.sentence.Text = app.audioN;
            
            %returns fileName
            app.fileName = app.audioN;
       	    %%%% ----------------------------
            %store actual SNR value
            app.snr_vecValues(app.audioExec) = app.snr_value;
        end
        
        function updtWordButtons(app)
            % zero selected words
            app.palavrasCorretas.Value = 0;
            %% clear word buttons
            app.clearWordsButtons();
            %% new words
            % Load metadata from audio
            fullFileName = fullfile(app.FolderFullPath,strcat(app.fileName,'.wav'));
            if ~exist(fullFileName, 'file')
                fullFileName(end-2:end) = 'WAV';   %if the .wav file does not exist, change extension to .WAV
            end
            
       	    % Le audio e metadata
            audioInformations = audioinfo(fullFileName);
            app.fraseExecucao.Value = audioInformations.Title;
            
            % Number of words per sentence
            app.sentenceWords = strsplit(audioInformations.Title);
            app.totalPalavras.Value = length(app.sentenceWords);
            
            % update words buttons
            for iWord=1:length(app.sentenceWords)
                app.wordsAll{app.audioExec,iWord} = app.sentenceWords(iWord);
                eval(sprintf('app.p1Button_%.0f.Text=app.sentenceWords{%.0f};',iWord,iWord))
                eval(sprintf('app.p1Button_%.0f.Visible = ''on'';',iWord))
            end    
        end
        
        function runTestSentence(app)
            closeElectdgrmFigs(); %close previous electrodograms
            %% Get audio from Alcaim1_ randomly
            app.getNewAudio();
            
            %% update words buttons
            app.updtWordButtons();
            
            %% Process audio
            app.StatusdereproduoLamp.Color = 'blue'; % update status
            noise = app.noisetypeButtonGroup.SelectedObject.Text;
            switch(noise)
                case 'ICRA'
                    noiseFileName  = 'ICRA_No01_16kHz_118s.wav';
                case 'babble'
                    noiseFileName  = 'cafeteria_babble.wav';
                case 'SSN_IEEE'
                    noiseFileName  = 'SSN_IEEE.wav';
                otherwise
                    disp('Options: ICRA | babble | SSN_IEEE');
            end
            noisePath = fullfile('Sounds','Noise');
            noisefile = fullfile(noisePath,noiseFileName);
            
            % Load speech audio
            speechfile = fullfile(app.FolderFullPath,strcat(app.fileName,'.wav'));
            if ~exist(speechfile, 'file')
                speechfile(end-2:end) = 'WAV';   %if the .wav file does not exist, change extension to .WAV
            end
            
            sgnls = SignalY(speechfile, noisefile, app.snr_value);
            y = sgnls.Smix;
            x = sgnls.target;
            v = sgnls.noise;
            
            % noise reduction
            % noise reduction technique: 'EnvEst', 'WF', 'BM','none'
            switch app.GainButtonGroup.SelectedObject.Text
                case 'Envelope Filter'
                    tech = 'EnvEst'; 
                case 'Wiener Filter'
                    tech = 'WF';
                case 'Binary Mask'
                    tech = 'BM';
                case 'Unprocessed'
                    tech = 'none';
            end
            %% stimulate
            app.stimuli = mod_stimulateGT_NR(y,x,v, app.map, tech, app.plotFlag.Value); %use gammatone filters
            
            % minimize electrodogram figures
            pauseTime = 3;
            minElectdgrmFigs(pauseTime);
            
            app.StatusdereproduoLamp.Color = 'red'; % update status
            
            %% reproduce audio signal
            % 
            try
                Stream_2(app.stimuli);
                % Increment list of execution audios
                app.audioExec = app.audioExec + 1;
            catch
                app.runTestSentence();
            end
            app.StatusdereproduoLamp.Color = 'green'; % update status
        end
        
        function enableScenario(app,onoffNoise,onoffNR)
            %% enable scenario configuration
            % noise selector
            Bttns = allchild(app.noisetypeButtonGroup); % finds all buttons of the group
            for jj = 1:length(Bttns)
                Bttns(jj).Enable = onoffNoise;
            end
            % NR selector
            Bttns = allchild(app.GainButtonGroup); % finds all buttons of the group
            for jj = 1:length(Bttns)
                Bttns(jj).Enable = onoffNR;
            end
        end
        
        function clearTestFields(app)
            app.fraseExecucao.Value = '';
            app.valueSNR.Text = '------';
            app.sentence.Text = '------';
            app.numberCicle.Text = '------';
            
            app.totalPalavras.Value = 0;
            app.palavrasCorretas.Value = 0;
        end
        
        function checklistitem(app)
            X = allchild(app.PanelChecklist); %get all children
            acc = 0; %number of boxes checked
            for ii = 1:length(X)
                if X(ii)~=app.checklistOKButton && X(ii).Value==1
                    acc = acc + 1;
                    X(ii).Enable = 'off';
                end
            end
            
            %if all boxes are checked, enable OK button
            if acc == length(X)-1;
                app.checklistOKButton.Enable = 'on';
            else
                app.checklistOKButton.Enable = 'off';
            end
        end
        
        function checkTestedGain(app)
            s=' ✓';
            gname = app.GainButtonGroup.SelectedObject; %selected gain
            gname.Text = strcat(gname.Text,s);
            
            app.gainscount = app.gainscount + 1;
            app.testedGains(app.gainscount) = gname;
            
            % disable selection of already tested gains
            for ii = 1:app.gainscount
                app.testedGains(ii).Enable = 'off';
                app.testedGains(ii).Value = false;
            end
            
            if app.gainscount < length(allchild(app.GainButtonGroup))
                msgbox('Choose another NR technique')
            else
                msgbox('Test is over :)')
            end
            
        end
        
        function listSpeechFiles(app)
            speechdir = fullfile('Sounds','Speech','Alcaim1_');
            app.speechList = subdirWavList(speechdir);
        end
    end   

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            close all
            app.UIFigure.Resize = 'off';
            %movegui(app.UIFigure,'center'); %induces error on versions 2016b+
            
            %go to CCIMod dir
            [~,current] = fileparts(pwd);
            while ~strcmp(current,'CCIMod')
                cd ..;
                [~,current] = fileparts(pwd);
            end
            init; %initialize path
            
            %clear word buttons
            app.clearWordsButtons();
            %initialize variables
            app.correctWords = cell(200,11);
            app.wordsAll = cell(200,11);
            app.audioN = '1st';
            app.previousAudio = cell(200,1); % Used to control which audio has been show
            app.previousAudio{1,1} = '1st';
            app.flagChooseOtherGain = 0; % control flag of first algorithm choose
            
            aux = allchild(app.GainButtonGroup);
            app.testedGains = gobjects(size(aux)) ; %graphics object array
            
            app.audioExec = 1; %start variable
            
            app.listSpeechFiles(); %creates a list of the speech database         
            
            %start log file
            fn = strcat(datestr(now,'ddmmmyy_HH.MM'),'.log');    %file name
            diary(fullfile('Logs',fn));
            
            % java obsolete warning turned off
            id = 'MATLAB:HandleGraphics:ObsoletedProperty:JavaFrame';
            warning('off',id)
                  
        end

        % Value changed function
        function StartButtonValueChanged(app, event)
            value = app.StartButton.Value;
            
            % disable scenario configuration
            app.enableScenario('off','off');
            
            % enable Stop button
            app.StopButton.Enable = 'on';
            
            % clear word buttons
            app.clearWordsButtons();
            
            % Flags two down-one up
            app.flag2down = 0;
            
            % Create vector of results (considering that, during each list, will be presented 200 sentences)
            app.Results = zeros(200,1);
            app.NumTotalWords = zeros(200,1);
            app.snr_vecValues = zeros(200,1);
            
            %             if app.flagChooseOtherGain == 0
            %                 % This index has to be related to randomly list
            %                 app.audioExec = 1;
            %             end
            
            % Disable button
            app.StartButton.Enable = 'off';
            
            % Counter of cicles (6)
            app.flagCounterCicles = 0;
            
            % Control SNR step (0 - 4dB / 1 - 2dB). Update based on app.correctRate variable
            app.SNRStep = 0;
            
            % Initial SNR presented is 16dB
            app.valueSNR.Text = '16dB';
            app.snr_value = 16;
            
            % Run next sentence of the test
            app.runTestSentence();
            
            % enable buttons
            app.ComputeButton.Enable = 'on';
            app.repeatLastAudio.Enable = 'on';
            app.selectAll.Enable = 'on';
            app.selectNone.Enable = 'on';
            
            %indicates first audio is played, thus there is no difference in direction od SNR step
            app.diff_old = 0;
        end

        % Button pushed function
        function ComputeButtonPushed(app, event)
            % Indicate numbers of audios executed (max = 200)
            app.NumberAudio.Text = num2str(app.audioExec-1);
            
            %% calculate number of correct words
            cumCorr = 0;
            
            for iWord=1:length(app.sentenceWords)
                eval(sprintf('value = app.p1Button_%.0f.Value;',iWord))
                eval(sprintf('palavra = app.p1Button_%.0f.Text;',iWord))
                if value == 1                    
                    cumCorr = cumCorr + 1;
                    app.correctWords{app.audioExec,iWord} = palavra;                    
                end
            end
            
            %% store data
            app.palavrasCorretas.Value = cumCorr;
            app.Results(app.audioExec) = app.palavrasCorretas.Value;
            app.NumTotalWords(app.audioExec) = app.totalPalavras.Value;
            %app.snr_vecValues(app.audioExec) = app.snr_value;
            
            %% enable Next button
            app.NextButton.Enable = 'on';
            app.NextButton.Value = 0;
            
            %% compute ratio of correct words, then update variables app.correctRate and app.snr_value
            app.correctRate = app.palavrasCorretas.Value/app.totalPalavras.Value;
            
        end

        % NextButton value changed function
        function NextButtonValueChanged(app, event)
            %value = app.NextButton.Value;
            %% update variables based on the last correctRatio
            cond = [(app.correctRate >= .5) , (app.SNRStep == 0) , (app.flag2down == 0)];
            xcond = sum([4, 2, 1]*cond');
            %disp(xcond) %for development matters only

            switch xcond
               	case 7
                    app.flag2down = 1;
               	case 6
                    app.snr_value = app.snr_value - 4;
                    app.flag2down = 0;
               	case 5
                    app.flag2down = 1;
               	case 4
                    app.snr_value = app.snr_value - 2;
                    app.flag2down = 0;
               	otherwise
                    app.snr_value = app.snr_value + 2;
                    app.SNRStep = 1;
                    app.flag2down = 0;
            end
            
            %% evaluate cicles
            %% TO DO test condition do count cicles
            diff0 = sign(app.snr_value - app.snr_vecValues(app.audioExec-1)); %sign of the derivative
            if (app.diff_old == 0)
                app.diff_old = diff0;
            end    
            if (diff0 ~= 0) && (diff0 ~= app.diff_old)
                % Counter of cicles
                app.flagCounterCicles = app.flagCounterCicles + 1;
                app.diff_old = diff0;    %update diff_old
            end
            %%
            % Update SNR and cicle #
            app.valueSNR.Text = strcat(num2str(app.snr_value),'dB');
            app.numberCicle.Text = num2str(app.flagCounterCicles);
            
            % Counter of completed inversions (maximum = 7)
            if app.flagCounterCicles >= 7
                
                % disable buttons
                app.ComputeButton.Enable = 'off';
                app.NextButton.Enable = 'off';
                
                
                % save all variables
                disp('Save results')
                
                resultados.numTotalPalavras = app.NumTotalWords;
                resultados.numAcertos = app.Results;
                resultados.palCorretas = app.correctWords;
                resultados.wordsAll = app.wordsAll;
                resultados.snr_vecValues = app.snr_vecValues;
                
                olddir = pwd;
                cd('Results');
                gain = app.GainButtonGroup.SelectedObject.Text;
                fname = strcat(gain(1:6),'.mat');
                [savefilename,savepathname] = uiputfile(fname,'Save results as');
                cd(olddir)
                save(fullfile(savepathname,savefilename),'resultados')
                app.clearWordsButtons();
                
                %enable Start
                app.StartButton.Value = 0;
                app.StartButton.Enable = 'on';
                
                app.flagChooseOtherGain = 1;
                
                % clear fields
                clearTestFields(app)
                % enable scenario configuration
                app.enableScenario('off','on')
                % update NR selection options
                app.checkTestedGain();
                return;
            end
            
            %%
            %             % Increment list of execution audios
            %             app.audioExec = app.audioExec + 1;
            
            % Disable buttons
            app.ComputeButton.Enable = 'off';
            app.NextButton.Enable = 'off';
            
            % Run next sentence of the test
            app.runTestSentence();
            app.ComputeButton.Enable = 'on';
        end

        % Value changed function
        function repeatLastAudioValueChanged(app, event)
            value = app.repeatLastAudio.Value;            
            %disable button
            app.repeatLastAudio.Enable = 'off';
            
            % play audio
            app.StatusdereproduoLamp.Color = 'Red';
            Stream_2(app.stimuli);
            app.StatusdereproduoLamp.Color = 'Green';
            
            % Enable Repeat button
            app.repeatLastAudio.Value = 0;
            app.repeatLastAudio.Enable = 'on';
            
        end

        % StopButton value changed function
        function StopButtonValueChanged(app)
            %open dialog box to check if the user really wants to stop
            msg = {'Are you sure you want to stop the experiment?',...
                'All data will be lost'};
            choice = questdlg(msg, 'Stop experiment', ...
                'Yes, quit','No, return to the test', ...
                'No, return to the test');
            if choice(1) == 'N'
                app.StopButton.Value = 0;
            else
                app.closeW();
            end
        end

        % checklistOKButton value changed function
        function chcklstOK(app)
            %value = app.checklistOKButton.Value;
            app.PanelChecklist.Visible = 'off'; %turns panel invisible
            app.enableScenario('off','off');
            app.plotFlag.Enable = 'on';
            app.totalPalavras.Value = 0;
            app.fraseExecucao.Value = '';
            app.ButtonPlayTest.Visible = 'off';
            app.Push2TalkButton.Visible = 'on';
        end

        % CheckBoxTerm value changed function
        function CBtermFcn(app)
            %value = app.CheckBoxTerm.Value;
            app.checklistitem();
        end

        % CheckBoxExplain value changed function
        function CBExplainFcn(app)
            %value = app.CheckBoxExplain.Value;
            app.checklistitem();
        end

        % CheckBoxVolume value changed function
        function CBVolFcn(app)
            %value = app.CheckBoxVolume.Value;
            app.checklistitem();
            app.KnobGain.Enable = 'on';
            app.ButtonPlayTest.Enable = 'on';
        end

        % CheckBoxScenario value changed function
        function CBScenFcn(app)
            value = app.CheckBoxScenario.Value;
            app.checklistitem();
            app.KnobGain.Enable = 'off';
            app.ButtonPlayTest.Enable = 'off';            
        end

        % UIFigure close request function
        function closeW(app)
            diary off;
            fname = fullfile('GUI','map.mat');
            if exist(fname, 'file')
                delete(fname);
            end
            app.delete;
        end

        % selectAll button pushed function
        function funSelectAll(app)
            % select all word buttons
            for iWord=1:length(app.sentenceWords)
                eval(sprintf('app.p1Button_%.0f.Value=1;',iWord));
            end
        end

        % selectNone button pushed function
        function funSelectNone(app)
            % unselect all word buttons
            for iWord=1:length(app.sentenceWords)
                eval(sprintf('app.p1Button_%.0f.Value=0;',iWord));
            end
        end

        % KnobGain value changed function
        function levelknob(app)
            %Controls the gain of the left
            value = app.KnobGain.Value;            
            if (isfield(app.map,'Left') == 1)
                app.map.Left.Gain = value;
                app.map.Left.gains_dB = app.map.Left.Gain + app.map.Left.BandGains;
                app.map.Left.gains = 10 .^ (app.map.Left.gains_dB / 20.0);
                
                app.map_int.Left.Gain = value;
                app.map_int.Left.gains_dB = app.map.Left.Gain + app.map.Left.BandGains;
                app.map_int.Left.gains = 10 .^ (app.map.Left.gains_dB / 20.0);
            end
            if (isfield(app.map,'Right') == 1)
                app.map.Right.Gain = value;
                app.map.Right.gains_dB = app.map.Right.Gain + app.map.Right.BandGains;
                app.map.Right.gains = 10 .^ (app.map.Right.gains_dB / 20.0);
                
                app.map_int.Right.Gain = value;
                app.map_int.Right.gains_dB = app.map.Right.Gain + app.map.Right.BandGains;
                app.map_int.Right.gains = 10 .^ (app.map.Right.gains_dB / 20.0);
            end
        end

        % CheckBoxLoad value changed function
        function CBLoadMapFcn(app)
            %value = app.CheckBoxLoad.Value;
            app.checklistitem();
            %load patient map and starts the board
            app.UIFigure.Visible = 'off'; 
            mapFile = chooseMAP();
            app.UIFigure.Visible = 'on';
            app.map = initialize_ACE2(mapFile);
            app.map_int = initialize_ACE_integer_ppf2(mapFile);
            fname = fullfile('GUI','map.mat');
            
            %save integer ppf map to supply this info to realtime app
            p = app.map_int;
            save(fname, 'p')
            
            %allow realtime communication
            app.Push2TalkButton.Enable = 'on';
            
            %updates level value on maps
            app.levelknob
        end

        % ButtonPlayTest button pushed function
        function runTrainningSentence(app)
            app.ButtonPlayTest.Enable = 'off';
            closeElectdgrmFigs(); %close previous electrodograms
            %% randomly choose trainning audio
            speechdir = fullfile('Sounds','Speech','Trainning');
            lst = subdirWavList(speechdir);
            idx = randi([1,length(lst)],1);
            speechFile = lst{idx};
            [app.FolderFullPath, app.fileName] = fileparts(speechFile);
            %% display sentence
            app.updtWordButtons();
            app.clearWordsButtons();
            %% Process
            app.StatusdereproduoLamp.Color = 'blue'; % update status
            plotflag = 1;
            app.stimuli = mod_stimulateGT( speechFile , app.map, plotflag );
            
            % minimize electrodogram figures
            pauseTime = 3;
            minElectdgrmFigs(pauseTime);
                
            %% Stream
            app.StatusdereproduoLamp.Color = 'red'; % update status
            Stream_2(app.stimuli);
            app.StatusdereproduoLamp.Color = 'green'; % update status
            
            app.ButtonPlayTest.Enable = 'on';
        end

        % Push2TalkButton value changed function
        function RT_TalkFcn(app)
            value = app.Push2TalkButton.Value;
            if value == 1
                setappdata(0,'map',app.map_int);
                hf=RealtimeStimulatorUFSC;
                while(isvalid(hf)) %wait while the RT stimulation is on
                    pause(1)
                end
                fname = fullfile('GUI','map.mat');
                z = load(fname); %loads the map changed on the realtime app
                app.map_int = z.p;
                clear z;
            end
            app.Push2TalkButton.Value = 0;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'CI Psychoacoustic Experiment';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeW);
            setAutoResize(app, app.UIFigure, true)
            app.ScenarioconfigurationPanel = uipanel(app.UIFigure);
            app.ScenarioconfigurationPanel.Title = 'Scenario configuration';
            app.ScenarioconfigurationPanel.Units = 'pixels';
            app.noisetypeButtonGroup = uibuttongroup(app.ScenarioconfigurationPanel);
            app.noisetypeButtonGroup.Title = 'noise type';
            app.noisetypeButtonGroup.Units = 'pixels';
            app.babble = uiradiobutton(app.noisetypeButtonGroup);
            app.babble.Text = 'babble';
            app.ICRA = uiradiobutton(app.noisetypeButtonGroup);
            app.ICRA.Value = true;
            app.ICRA.Text = 'ICRA';
            app.SSN_IEEE = uiradiobutton(app.noisetypeButtonGroup);
            app.SSN_IEEE.Text = 'SSN_IEEE';
            app.GainButtonGroup = uibuttongroup(app.ScenarioconfigurationPanel);
            app.GainButtonGroup.Title = 'NR Technique';
            app.GainButtonGroup.Units = 'pixels';
            app.MMSEButton = uiradiobutton(app.GainButtonGroup);
            app.MMSEButton.Text = 'Envelope Filter';
            app.WienerButton = uiradiobutton(app.GainButtonGroup);
            app.WienerButton.Text = 'Wiener Filter';
            app.BinaryMaskButton = uiradiobutton(app.GainButtonGroup);
            app.BinaryMaskButton.Text = 'Binary Mask';
            app.UnprocessedButton = uiradiobutton(app.GainButtonGroup);
            app.UnprocessedButton.Value = true;
            app.UnprocessedButton.Text = 'Unprocessed';
            app.AdaptativeintelligibilitytestprotocolPanel = uipanel(app.UIFigure);
            app.AdaptativeintelligibilitytestprotocolPanel.Title = 'Adaptative intelligibility test protocol';
            app.AdaptativeintelligibilitytestprotocolPanel.Units = 'pixels';
            app.AdaptativetestprogressPanel = uipanel(app.UIFigure);
            app.AdaptativetestprogressPanel.Title = 'Adaptative test progress';
            app.AdaptativetestprogressPanel.Units = 'pixels';
            app.SNRLabel = uilabel(app.AdaptativetestprogressPanel);
            app.SNRLabel.Text = 'SNR: ';
            app.valueSNR = uilabel(app.AdaptativetestprogressPanel);
            app.valueSNR.Text = '------';
            app.AudiosentenceLabel = uilabel(app.AdaptativetestprogressPanel);
            app.AudiosentenceLabel.Text = 'Audio sentence: ';
            app.sentence = uilabel(app.AdaptativetestprogressPanel);
            app.sentence.Text = '------';
            app.CicletwodownoneupLabel = uilabel(app.AdaptativetestprogressPanel);
            app.CicletwodownoneupLabel.Text = 'Cicle (2 down-1 up):';
            app.numberCicle = uilabel(app.AdaptativetestprogressPanel);
            app.numberCicle.Text = '------';
            app.NumberAudio = uilabel(app.AdaptativetestprogressPanel);
            app.NumberAudio.Text = '------';
            app.audioLabel = uilabel(app.AdaptativetestprogressPanel);
            app.audioLabel.Text = '# of computed audios:';
            app.DatarepresentationPanel = uipanel(app.UIFigure);
            app.DatarepresentationPanel.Title = 'Data representation';
            app.DatarepresentationPanel.Units = 'pixels';
            app.udioemexecuoTextAreaLabel = uilabel(app.DatarepresentationPanel);
            app.udioemexecuoTextAreaLabel.Text = 'Sentence ';
            app.fraseExecucao = uitextarea(app.DatarepresentationPanel);
            app.fraseExecucao.Editable = 'off';
            app.StatusdereproduoLampLabel = uilabel(app.DatarepresentationPanel);
            app.StatusdereproduoLampLabel.Text = 'Status:';
            app.StatusdereproduoLamp = uilamp(app.DatarepresentationPanel);
            app.VerdeudioexecutandoVermelhoOuvintepronunciandoAzulLabel = uilabel(app.DatarepresentationPanel);
            app.VerdeudioexecutandoVermelhoOuvintepronunciandoAzulLabel.Text = {'Green: Stand by'; 'Red:   Playing audio'; 'Blue:  Processing audio'};
            app.p1Button_1 = uibutton(app.DatarepresentationPanel, 'state');
            app.p1Button_1.Text = 'p1';
            app.p1Button_2 = uibutton(app.DatarepresentationPanel, 'state');
            app.p1Button_2.Text = 'p1';
            app.p1Button_3 = uibutton(app.DatarepresentationPanel, 'state');
            app.p1Button_3.Text = 'p1';
            app.p1Button_4 = uibutton(app.DatarepresentationPanel, 'state');
            app.p1Button_4.Text = 'p1';
            app.p1Button_5 = uibutton(app.DatarepresentationPanel, 'state');
            app.p1Button_5.Text = 'p1';
            app.p1Button_6 = uibutton(app.DatarepresentationPanel, 'state');
            app.p1Button_6.Text = 'p1';
            app.p1Button_7 = uibutton(app.DatarepresentationPanel, 'state');
            app.p1Button_7.Text = 'p1';
            app.p1Button_8 = uibutton(app.DatarepresentationPanel, 'state');
            app.p1Button_8.Text = 'p1';
            app.p1Button_9 = uibutton(app.DatarepresentationPanel, 'state');
            app.p1Button_9.Text = 'p1';
            app.Panel = uipanel(app.DatarepresentationPanel);
            app.Panel.Units = 'pixels';
            app.TotalwordscorrectLabel = uilabel(app.Panel);
            app.TotalwordscorrectLabel.Text = 'Correct words count';
            app.palavrasCorretas = uieditfield(app.Panel, 'numeric');
            app.palavrasCorretas.Editable = 'off';
            app.NmerototaldepalavrasEditFieldLabel = uilabel(app.Panel);
            app.NmerototaldepalavrasEditFieldLabel.Text = 'Total words';
            app.totalPalavras = uieditfield(app.Panel, 'numeric');
            app.totalPalavras.Editable = 'off';
            app.NextButton = uibutton(app.Panel, 'state');
            app.NextButton.ValueChangedFcn = createCallbackFcn(app, @NextButtonValueChanged);
            app.NextButton.Enable = 'off';
            app.NextButton.Text = 'Next';
            app.ComputeButton = uibutton(app.Panel, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed);
            app.ComputeButton.Enable = 'off';
            app.ComputeButton.Text = 'Compute';
            app.p1Button_10 = uibutton(app.DatarepresentationPanel, 'state');
            app.p1Button_10.Text = 'p1';
            app.p1Button_11 = uibutton(app.DatarepresentationPanel, 'state');
            app.p1Button_11.Text = 'p1';
            app.repeatLastAudio = uibutton(app.DatarepresentationPanel, 'state');
            app.repeatLastAudio.ValueChangedFcn = createCallbackFcn(app, @repeatLastAudioValueChanged);
            app.repeatLastAudio.Enable = 'off';
            app.repeatLastAudio.Text = 'Repeat';
            app.selectAll = uibutton(app.DatarepresentationPanel, 'push');
            app.selectAll.ButtonPushedFcn = createCallbackFcn(app, @funSelectAll);
            app.selectAll.Enable = 'off';
            app.selectAll.Text = 'All correct';
            app.selectNone = uibutton(app.DatarepresentationPanel, 'push');
            app.selectNone.ButtonPushedFcn = createCallbackFcn(app, @funSelectNone);
            app.selectNone.Enable = 'off';
            app.selectNone.Text = 'None correct';
            app.plotFlag = uicheckbox(app.DatarepresentationPanel);
            app.plotFlag.Enable = 'off';
            app.plotFlag.Text = 'Plot Electrodogram';
            app.Push2TalkButton = uibutton(app.DatarepresentationPanel, 'state');
            app.Push2TalkButton.ValueChangedFcn = createCallbackFcn(app, @RT_TalkFcn);
            app.Push2TalkButton.Enable = 'off';
            app.Push2TalkButton.Text = 'Push to talk';
            app.ControlpanelPanel = uipanel(app.UIFigure);
            app.ControlpanelPanel.Title = 'Control panel';
            app.ControlpanelPanel.Units = 'pixels';
            app.StartButton = uibutton(app.ControlpanelPanel, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.ControlpanelPanel, 'state');
            app.StopButton.ValueChangedFcn = createCallbackFcn(app, @StopButtonValueChanged);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.PanelChecklist = uipanel(app.UIFigure);
            app.PanelChecklist.Title = 'Pre-test checklist';
            app.PanelChecklist.Units = 'pixels';
            app.checklistOKButton = uibutton(app.PanelChecklist, 'state');
            app.checklistOKButton.ValueChangedFcn = createCallbackFcn(app, @chcklstOK);
            app.checklistOKButton.Enable = 'off';
            app.checklistOKButton.Text = 'OK';
            app.CheckBoxTerm = uicheckbox(app.PanelChecklist);
            app.CheckBoxTerm.ValueChangedFcn = createCallbackFcn(app, @CBtermFcn);
            app.CheckBoxTerm.Text = 'Term of consent';
            app.CheckBoxExplain = uicheckbox(app.PanelChecklist);
            app.CheckBoxExplain.ValueChangedFcn = createCallbackFcn(app, @CBExplainFcn);
            app.CheckBoxExplain.Text = 'Explain the test';
            app.CheckBoxVolume = uicheckbox(app.PanelChecklist);
            app.CheckBoxVolume.ValueChangedFcn = createCallbackFcn(app, @CBVolFcn);
            app.CheckBoxVolume.Text = 'Adjust volume';
            app.CheckBoxScenario = uicheckbox(app.PanelChecklist);
            app.CheckBoxScenario.ValueChangedFcn = createCallbackFcn(app, @CBScenFcn);
            app.CheckBoxScenario.Text = 'Verify scenario configuration';
            app.CheckBoxLoad = uicheckbox(app.PanelChecklist);
            app.CheckBoxLoad.ValueChangedFcn = createCallbackFcn(app, @CBLoadMapFcn);
            app.CheckBoxLoad.Text = 'Load map';
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'Gain Control';
            app.Panel2.Units = 'pixels';
            app.LabelKnob = uilabel(app.Panel2);
            app.LabelKnob.Enable = 'off';
            app.LabelKnob.Text = 'Master Gain [dB]';
            app.KnobGain = uiknob(app.Panel2, 'continuous');
            app.KnobGain.Limits = [0 60];
            app.KnobGain.MajorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60];
            app.KnobGain.ValueChangedFcn = createCallbackFcn(app, @levelknob);
            app.KnobGain.Enable = 'off';
            app.KnobGain.Value = 10;
            app.ButtonPlayTest = uibutton(app.Panel2, 'push');
            app.ButtonPlayTest.ButtonPushedFcn = createCallbackFcn(app, @runTrainningSentence);
            app.ButtonPlayTest.Enable = 'off';
            app.ButtonPlayTest.Text = 'Play next training audio';
        end
    end

    methods (Access = public)

        % Construct app
        function app = interface_2down1up()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end