classdef interface_expIC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure                   % CI Psy...
        DatarepresentationPanel matlab.ui.container.Panel          % Data r...
        udioemexecuoTextAreaLabel matlab.ui.control.Label % Sentence 
        fraseExecucao           matlab.ui.control.TextArea        
        StatusdereproduoLampLabel matlab.ui.control.Label % Status:
        StatusdereproduoLamp    matlab.ui.control.Lamp            
        VerdeudioexecutandoVermelhoOuvintepronunciandoAzulLabel matlab.ui.control.Label
        p1Button_1              matlab.ui.control.StateButton      % p1
        p1Button_2              matlab.ui.control.StateButton      % p1
        p1Button_3              matlab.ui.control.StateButton      % p1
        p1Button_4              matlab.ui.control.StateButton      % p1
        p1Button_5              matlab.ui.control.StateButton      % p1
        p1Button_6              matlab.ui.control.StateButton      % p1
        p1Button_7              matlab.ui.control.StateButton      % p1
        p1Button_8              matlab.ui.control.StateButton      % p1
        p1Button_9              matlab.ui.control.StateButton      % p1
        Panel                   matlab.ui.container.Panel         
        TotalwordscorrectLabel  matlab.ui.control.Label            % Correc...
        palavrasCorretas        matlab.ui.control.NumericEditField % [-Inf ...
        NmerototaldepalavrasEditFieldLabel matlab.ui.control.Label % Total ...
        totalPalavras           matlab.ui.control.NumericEditField % [-Inf ...
        NextButton              matlab.ui.control.StateButton      % Next
        ComputeButton           matlab.ui.control.Button           % Compute
        p1Button_10             matlab.ui.control.StateButton      % p1
        p1Button_11             matlab.ui.control.StateButton      % p1
        repeatLastAudio         matlab.ui.control.StateButton      % Repeat
        selectAll               matlab.ui.control.Button           % All co...
        selectNone              matlab.ui.control.Button           % None c...
        plotFlag                matlab.ui.control.CheckBox         % Plot E...
        TabGroup                matlab.ui.container.TabGroup       % Set-up...
        TabSetup                matlab.ui.container.Tab            % Set-up
        PanelChecklist          matlab.ui.container.Panel          % Pre-te...
        checklistOKButton       matlab.ui.control.StateButton      % OK
        CheckBoxTerm            matlab.ui.control.CheckBox         % Term o...
        CheckBoxExplain         matlab.ui.control.CheckBox         % Explai...
        CheckBoxVolume          matlab.ui.control.CheckBox         % Adjust...
        CheckBoxLoad            matlab.ui.control.CheckBox         % Load map
        PanelGainControl        matlab.ui.container.Panel          % Gain C...
        LabelKnob               matlab.ui.control.Label            % Master...
        KnobGain                matlab.ui.control.Knob             % [0 60]
        ButtonPlayTest          matlab.ui.control.Button           % Play n...
        TabIntel                matlab.ui.container.Tab            % Intell...
        ControlpanelPanel       matlab.ui.container.Panel          % Contro...
        StartButton             matlab.ui.control.StateButton      % Start
        StopButton              matlab.ui.control.StateButton      % Stop
        AdaptativetestprogressPanel matlab.ui.container.Panel % Adaptative ...
        SNRLabel                matlab.ui.control.Label            % SNR:
        valueSNR                matlab.ui.control.Label            % ------
        AudiosentenceLabel      matlab.ui.control.Label            % Audio ...
        sentence                matlab.ui.control.Label            % ------
        NumberAudio             matlab.ui.control.Label            % ------
        audioLabel              matlab.ui.control.Label            % audio # :
        Label3                  matlab.ui.control.Label            % Noise: 
        Label4                  matlab.ui.control.Label            % 4-talk...
        Label5                  matlab.ui.control.Label            % ------
        TabQlt                  matlab.ui.container.Tab            % Quality
        StateButtonA            matlab.ui.control.StateButton      % A
        StateButtonB            matlab.ui.control.StateButton      % B
        LabelSlider             matlab.ui.control.Label            % Prefer...
        Slider                  matlab.ui.control.Slider           % [-1 1]
        Button                  matlab.ui.control.Button           % OK
        Panel3                  matlab.ui.container.Panel          % Live s...
        Push2TalkButton         matlab.ui.control.StateButton      % Push t...
        conditionsPanel         matlab.ui.container.Panel         
        CBUnp                   matlab.ui.control.CheckBox         % Unproc.
        SRT29Unp                matlab.ui.control.CheckBox         % SRT29
        SRT50Unp                matlab.ui.control.CheckBox         % SRT50
        SRT71Unp                matlab.ui.control.CheckBox         % SRT71
        CBWie                   matlab.ui.control.CheckBox         % Wiener
        SRT29Wie                matlab.ui.control.CheckBox         % SRT29
        SRT50Wie                matlab.ui.control.CheckBox         % SRT50
        SRT71Wie                matlab.ui.control.CheckBox         % SRT71
        CBEnv                   matlab.ui.control.CheckBox         % Env. E...
        SRT29Env                matlab.ui.control.CheckBox         % SRT29
        SRT50Env                matlab.ui.control.CheckBox         % SRT50
        SRT71Env                matlab.ui.control.CheckBox         % SRT71
    end

    
    properties (Access = private)
        stimuli              % Description
        SNRStep              % Description
        snr_value               % Description
        earref                  % Description
        AZN                     % Description
        correctRate             % Description
        sentenceWords           % Description
        FolderFullPath          % Description
        fileName                % Description
        correctWords            % Description
        Results                 % Description
        NumTotalWords           % Description
        audioExec               % Description
        wordsAll                % Description
        snr_vecValues           % Description
        MF                      % Description
        folder1                 % Description
        audioN                  % Description
        previousAudio           % Description
        channelOutput           % Description
        flagVSameAudio          % Description
        flagAudioRepeated       % Description
        diff_old                % old derivative sign
        testedGains             % Description
        gainscount = 0;         % Description
        speechList              % list of speech files
        map                     % patient map
        map_int                 % patient map for real-time stimulation (integer ppf)        
        oldTab                  % dummy used on tabctrl function
        scenario                % actual scenario
        adapstate               % state of the variables of the adaptative process
        usedLists               % already used lists
        NRlist                  % list of noise reduction methods
        SRTlist                 % list of SRT targets
    end

    properties (Access = public)
        tabctrlFlag = 0;            % flag to control tab behaviour (0: changes are not allowed)
    end
    
    methods (Access = private)
        
        function clearWordsButtons(app)
            % Clear sentence words buttons
            for iWord=1:11
                eval(sprintf('app.p1Button_%.0f.Visible = ''off'';',iWord))
                eval(sprintf('app.p1Button_%.0f.Value = 0;',iWord))
            end
        end
             
        
        function getNewAudio(app)
            % Randomly get new audio from Alcaim1_
            
            while(sum(strcmp(app.previousAudio, app.audioN)))
                %%randomly pick a speech file
                L = length(app.speechList); %number of speech filesin the database
                idx = randi([1,L],1); %index of the randomly chosen file
                [fpath, fname] = fileparts(app.speechList{idx});
                app.audioN = fname;
                app.FolderFullPath = fpath;
            end            
            
            %store selected audio to history of played audios
            app.previousAudio{app.audioExec,1} = app.audioN;
            
            %update file sentence name
            app.sentence.Text = app.audioN;
            
            %returns fileName
            app.fileName = app.audioN;
       	    %%%% ----------------------------
            %store actual SNR value
            app.snr_vecValues(app.audioExec) = app.snr_value;
        end
        
        function updtWordButtons(app)
            % zero selected words
            app.palavrasCorretas.Value = 0;
            %% clear word buttons
            app.clearWordsButtons();
            %% new words
            % Load metadata from audio
            fullFileName = fullfile(app.FolderFullPath,strcat(app.fileName,'.wav'));
            if ~exist(fullFileName, 'file')
                fullFileName(end-2:end) = 'WAV';   %if the .wav file does not exist, change extension to .WAV
            end
            
       	    % Le audio e metadata
            audioInformations = audioinfo(fullFileName);
            app.fraseExecucao.Value = audioInformations.Title;
            
            % Number of words per sentence
            app.sentenceWords = strsplit(audioInformations.Title);
            app.totalPalavras.Value = length(app.sentenceWords);
            
            % update words buttons
            for iWord=1:length(app.sentenceWords)
                app.wordsAll{app.audioExec,iWord} = app.sentenceWords(iWord);
                eval(sprintf('app.p1Button_%.0f.Text=app.sentenceWords{%.0f};',iWord,iWord))
                eval(sprintf('app.p1Button_%.0f.Visible = ''on'';',iWord))
            end    
        end
        
        function runTestSentence(app)
            closeElectdgrmFigs(); %close previous electrodograms
            %% Get audio from Alcaim1_ randomly
            app.getNewAudio();
            
            %% update words buttons
            app.updtWordButtons();
            
            %% Process audio
            app.StatusdereproduoLamp.Color = 'blue'; % update status
            noise = app.noisetypeButtonGroup.SelectedObject.Text;
            switch(noise)
                case 'ICRA'
                    noiseFileName  = 'ICRA_No01_16kHz_118s.wav';
                case 'babble'
                    noiseFileName  = 'cafeteria_babble.wav';
                case 'SSN_IEEE'
                    noiseFileName  = 'SSN_IEEE.wav';
                otherwise
                    disp('Options: ICRA | babble | SSN_IEEE');
            end
            noisePath = fullfile('Sounds','Noise');
            noisefile = fullfile(noisePath,noiseFileName);
            
            % Load speech audio
            speechfile = fullfile(app.FolderFullPath,strcat(app.fileName,'.wav'));
            if ~exist(speechfile, 'file')
                speechfile(end-2:end) = 'WAV';   %if the .wav file does not exist, change extension to .WAV
            end
            
            sgnls = SignalY(speechfile, noisefile, app.snr_value);
            y = sgnls.Smix;
            x = sgnls.target;
            v = sgnls.noise;
            
            % noise reduction
            % noise reduction technique: 'EnvEst', 'WF', 'BM','none'
            switch app.GainButtonGroup.SelectedObject.Text
                case 'Envelope Filter'
                    tech = 'EnvEst'; 
                case 'Wiener Filter'
                    tech = 'WF';
                case 'Binary Mask'
                    tech = 'BM';
                case 'Unprocessed'
                    tech = 'none';
            end
            %% stimulate
            app.stimuli = mod_stimulateGT_NR(y,x,v, app.map, tech, app.plotFlag.Value); %use gammatone filters
            
            % minimize electrodogram figures
            pauseTime = 3;
            minElectdgrmFigs(pauseTime);
            
            app.StatusdereproduoLamp.Color = 'red'; % update status
            
            %% reproduce audio signal
            % 
            try
                Stream_2(app.stimuli);
                % Increment list of execution audios
                app.audioExec = app.audioExec + 1;
            catch
                app.runTestSentence();
            end
            app.StatusdereproduoLamp.Color = 'green'; % update status
        end
        
        function clearTestFields(app)
            app.fraseExecucao.Value = '';
            app.valueSNR.Text = '------';
            app.sentence.Text = '------';
            app.numberCicle.Text = '------';
            
            app.totalPalavras.Value = 0;
            app.palavrasCorretas.Value = 0;
        end
        
        function checklistitem(app)
            X = allchild(app.PanelChecklist); %get all children
            acc = 0; %number of boxes checked
            for ii = 1:length(X)
                if X(ii)~=app.checklistOKButton && X(ii).Value==1
                    acc = acc + 1;
                    X(ii).Enable = 'off';
                end
            end
            
            %if all boxes are checked, enable OK button
            if acc == length(X)-1;
                app.checklistOKButton.Enable = 'on';
            else
                app.checklistOKButton.Enable = 'off';
            end
        end
        
        function listSpeechFiles(app)
            %randomly selects 2 lists
            speechdir = fullfile('Sounds','Speech','Alcaim1_');
            Mlsts = subdirList(fullfile(speechdir,'M'));
            Flsts = subdirList(fullfile(speechdir,'F'));
                        
            Mlsts = setdiff(Mlsts,app.usedLists);
            Flsts = setdiff(Flsts,app.usedLists);
            
            m = randi([1, length(Mlsts)]);
            f = randi([1, length(Flsts)]);
            
            lM = Mlsts{m};
            lF = Flsts{f};
            %lists all wav file in the lists
            listF = subdirWavList(lF)';
            listM = subdirWavList(lM)';
            app.speechList = [listF;listM];
        end

        function selectNR(app)
            off = 'off';
            on = 'on';
            
            if length(app.scenario.NR) > 2 ;
                NRabbr =  app.scenario.NR(1:3);
                cmd = sprintf('app.CB%s.Enable = off;', NRabbr);
                eval(cmd)
                if isnumeric(app.scenario.SRT)
                    NRabbr =  app.scenario.NR(1:3);
                    cmd = sprintf('app.SRT%d%s.Enable = off;', app.scenario.SRT, NRabbr);
                    eval(cmd)
                end
            end
            
            %select NR
            L = length(app.NRlist);
            ii = randi([1,L]);
            aux = app.NRlist{ii};
            app.scenario.NR = aux;
            
            NRabbr = aux(1:3);
            cmd = sprintf('app.CB%s.Enable = on;', NRabbr);
            eval(cmd)
            cmd = sprintf('app.CB%s.Value = 1;', NRabbr);
            eval(cmd)
            
            %update list
            app.NRlist = setdiff(app.NRlist,app.scenario.NR);
            app.set_SRTlist();
            
        end

        function selectSRT(app)
            off = 'off';
            on = 'on';
            if length(app.scenario.NR) > 2 && isnumeric(app.scenario.SRT);
                NRabbr =  app.scenario.NR(1:3);
                cmd = sprintf('app.SRT%d%s.Enable = off;', app.scenario.SRT, NRabbr);
                eval(cmd)
            end
            %select SRT
            L = length(app.SRTlist);
            ii = randi([1,L]);
            aux = app.SRTlist(ii);
            app.scenario.SRT = aux;
            
            NRabbr = app.scenario.NR(1:3);
            cmd = sprintf('app.SRT%d%s.Enable = on;', app.scenario.SRT, NRabbr);
            eval(cmd)
            cmd = sprintf('app.SRT%d%s.Value = 1;', app.scenario.SRT, NRabbr);
            eval(cmd)
            
            %update list
            app.SRTlist = setdiff(app.SRTlist,app.scenario.SRT);
        end

        function set_NRlist(app)
            app.NRlist = {'Unprocessed', 'Wiener', 'EnvEstim'};
        end

        function set_SRTlist(app)
            app.SRTlist = [29,50,71]';
        end

    end   


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            close all
            app.UIFigure.Resize = 'off';
            %movegui(app.UIFigure,'center'); %induces error on versions 2016b+
            
            %go to CCIMod dir
            [~,current] = fileparts(pwd);
            while ~strcmpi(current,'CCIMod')
                cd ..;
                [~,current] = fileparts(pwd);
            end
            init; %initialize path
            
            %clear word buttons
            app.clearWordsButtons();
            
            %initialize variables
            app.scenario.NR = '';
            app.scenario.SRT = '';
            app.adapstate.last2 = '--';
            app.adapstate.first = 1;
            
            app.usedLists = cell(20,1);
            for ii=1:20
                app.usedLists{ii} = '';
            end
            
            app.set_NRlist();
            app.set_SRTlist();
            
            %
            app.correctWords = cell(200,11);
            app.wordsAll = cell(200,11);
            app.audioN = '1st';
            app.previousAudio = cell(200,1); % Used to control which audio has been show
            app.previousAudio{1,1} = '1st';
                                
            app.audioExec = 1; %start variable
                        
            % tab management
            app.tabctrlFlag = 0;            
            app.oldTab = app.TabGroup.SelectedTab;
            
            %start log file
            fn = strcat(datestr(now,'ddmmmyy_HH.MM'),'.log');    %file name
            diary(fullfile('Logs',fn));
            
            % java obsolete warning turned off
            id = 'MATLAB:HandleGraphics:ObsoletedProperty:JavaFrame';
            warning('off',id)                 
        end

        % Button pushed function
        function ComputeButtonPushed(app, event)
            % Indicate numbers of audios executed (max = 200)
            app.NumberAudio.Text = num2str(app.audioExec-1);
            
            %% calculate number of correct words
            cumCorr = 0;
            
            for iWord=1:length(app.sentenceWords)
                eval(sprintf('value = app.p1Button_%.0f.Value;',iWord))
                eval(sprintf('palavra = app.p1Button_%.0f.Text;',iWord))
                if value == 1                    
                    cumCorr = cumCorr + 1;
                    app.correctWords{app.audioExec,iWord} = palavra;                    
                end
            end
            
            %% store data
            app.palavrasCorretas.Value = cumCorr;
            app.Results(app.audioExec) = app.palavrasCorretas.Value;
            app.NumTotalWords(app.audioExec) = app.totalPalavras.Value;
            %app.snr_vecValues(app.audioExec) = app.snr_value;
            
            %% enable Next button
            app.NextButton.Enable = 'on';
            app.NextButton.Value = 0;
            
            %% compute ratio of correct words, then update variables app.correctRate and app.snr_value
            app.correctRate = app.palavrasCorretas.Value/app.totalPalavras.Value;
            
        end

        % NextButton value changed function
        function NextButtonValueChanged(app, event)
            %value = app.NextButton.Value;
            %% update variables based on the last correctRatio
            cond = [(app.correctRate >= .5) , (app.SNRStep == 0) , (app.flag2down == 0)];
            xcond = sum([4, 2, 1]*cond');
            %disp(xcond) %for development matters only

            switch xcond
               	case 7
                    app.flag2down = 1;
               	case 6
                    app.snr_value = app.snr_value - 4;
                    app.flag2down = 0;
               	case 5
                    app.flag2down = 1;
               	case 4
                    app.snr_value = app.snr_value - 2;
                    app.flag2down = 0;
               	otherwise
                    app.snr_value = app.snr_value + 2;
                    app.SNRStep = 1;
                    app.flag2down = 0;
            end
            
            %% evaluate cicles
            %% TO DO test condition do count cicles
            diff0 = sign(app.snr_value - app.snr_vecValues(app.audioExec-1)); %sign of the derivative
            if (app.diff_old == 0)
                app.diff_old = diff0;
            end    
            if (diff0 ~= 0) && (diff0 ~= app.diff_old)
                % Counter of cicles
                app.flagCounterCicles = app.flagCounterCicles + 1;
                app.diff_old = diff0;    %update diff_old
            end
            %%
            % Update SNR and cicle #
            app.valueSNR.Text = strcat(num2str(app.snr_value),'dB');
            app.numberCicle.Text = num2str(app.flagCounterCicles);
            
            % Counter of completed inversions (maximum = 7)
            if app.flagCounterCicles >= 7
                
                % disable buttons
                app.ComputeButton.Enable = 'off';
                app.NextButton.Enable = 'off';
                
                
                % save all variables
                disp('Save results')
                
                resultados.numTotalPalavras = app.NumTotalWords;
                resultados.numAcertos = app.Results;
                resultados.palCorretas = app.correctWords;
                resultados.wordsAll = app.wordsAll;
                resultados.snr_vecValues = app.snr_vecValues;
                
                olddir = pwd;
                cd('Results');
                gain = app.GainButtonGroup.SelectedObject.Text;
                fname = strcat(gain(1:6),'.mat');
                [savefilename,savepathname] = uiputfile(fname,'Save results as');
                cd(olddir)
                save(fullfile(savepathname,savefilename),'resultados')
                app.clearWordsButtons();
                
                %enable Start
                app.StartButton.Value = 0;
                app.StartButton.Enable = 'on';
                
                app.flagChooseOtherGain = 1;
                
                % clear fields
                clearTestFields(app)
                % enable scenario configuration
                app.enableAllChild('off','on')
                % update NR selection options
                app.checkTestedGain();
                return;
            end
            
            %%
            %             % Increment list of execution audios
            %             app.audioExec = app.audioExec + 1;
            
            % Disable buttons
            app.ComputeButton.Enable = 'off';
            app.NextButton.Enable = 'off';
            
            % Run next sentence of the test
            app.runTestSentence();
            app.ComputeButton.Enable = 'on';
        end

        % Value changed function
        function repeatLastAudioValueChanged(app, event)
            value = app.repeatLastAudio.Value;            
            %disable button
            app.repeatLastAudio.Enable = 'off';
            
            % play audio
            app.StatusdereproduoLamp.Color = 'Red';
            Stream_2(app.stimuli);
            app.StatusdereproduoLamp.Color = 'Green';
            
            % Enable Repeat button
            app.repeatLastAudio.Value = 0;
            app.repeatLastAudio.Enable = 'on';
            
        end

        % StopButton value changed function
        function StopButtonValueChanged(app)
            %open dialog box to check if the user really wants to stop
            msg = {'Are you sure you want to stop the experiment?',...
                'All data will be lost'};
            choice = questdlg(msg, 'Stop experiment', ...
                'Yes, quit','No, return to the test', ...
                'No, return to the test');
            if choice(1) == 'N'
                app.StopButton.Value = 0;
            else
                app.closeW();
            end
        end

        % checklistOKButton value changed function
        function chcklstOK(app)
            %value = app.checklistOKButton.Value;
            %app.PanelChecklist.Visible = 'off'; %turns panel invisible
            %app.enableAllChild('off','off');
            app.plotFlag.Enable = 'on';
            app.totalPalavras.Value = 0;
            app.fraseExecucao.Value = '';
            app.ButtonPlayTest.Enable = 'off';
            
            app.tabctrlFlag = 1;
            app.TabGroup.SelectedTab = app.TabIntel;
            app.oldTab = app.TabIntel;
            
            app.checklistOKButton.Value = 0;
            app.checklistOKButton.Enable = 'off';
        end

        % CheckBoxTerm value changed function
        function CBtermFcn(app)
            %value = app.CheckBoxTerm.Value;
            app.checklistitem();
        end

        % CheckBoxExplain value changed function
        function CBExplainFcn(app)
            %value = app.CheckBoxExplain.Value;
            app.checklistitem();
        end

        % CheckBoxVolume value changed function
        function CBVolFcn(app)
            %value = app.CheckBoxVolume.Value;
            app.checklistitem();
            app.KnobGain.Enable = 'on';
            app.ButtonPlayTest.Enable = 'on';
        end

        % CheckBoxScenario value changed function
        function CBScenFcn(app)
            value = app.CheckBoxScenario.Value;
            app.checklistitem();
            app.KnobGain.Enable = 'off';
            app.ButtonPlayTest.Enable = 'off';            
        end

        % UIFigure close request function
        function closeW(app)
            diary off;
            fname = fullfile('GUI','map.mat');
            if exist(fname, 'file')
                delete(fname);
            end
            app.delete;
        end

        % selectAll button pushed function
        function funSelectAll(app)
            % select all word buttons
            for iWord=1:length(app.sentenceWords)
                eval(sprintf('app.p1Button_%.0f.Value=1;',iWord));
            end
        end

        % selectNone button pushed function
        function funSelectNone(app)
            % unselect all word buttons
            for iWord=1:length(app.sentenceWords)
                eval(sprintf('app.p1Button_%.0f.Value=0;',iWord));
            end
        end

        % KnobGain value changed function
        function levelknob(app)
            %Controls the gain of the left
            value = app.KnobGain.Value;            
            if (isfield(app.map,'Left') == 1)
                app.map.Left.Gain = value;
                app.map.Left.gains_dB = app.map.Left.Gain + app.map.Left.BandGains;
                app.map.Left.gains = 10 .^ (app.map.Left.gains_dB / 20.0);
                
                app.map_int.Left.Gain = value;
                app.map_int.Left.gains_dB = app.map.Left.Gain + app.map.Left.BandGains;
                app.map_int.Left.gains = 10 .^ (app.map.Left.gains_dB / 20.0);
            end
            if (isfield(app.map,'Right') == 1)
                app.map.Right.Gain = value;
                app.map.Right.gains_dB = app.map.Right.Gain + app.map.Right.BandGains;
                app.map.Right.gains = 10 .^ (app.map.Right.gains_dB / 20.0);
                
                app.map_int.Right.Gain = value;
                app.map_int.Right.gains_dB = app.map.Right.Gain + app.map.Right.BandGains;
                app.map_int.Right.gains = 10 .^ (app.map.Right.gains_dB / 20.0);
            end
        end

        % CheckBoxLoad value changed function
        function CBLoadMapFcn(app)
            %value = app.CheckBoxLoad.Value;
            app.checklistitem();
            %load patient map and starts the board
            app.UIFigure.Visible = 'off'; 
            mapFile = chooseMAP();
            app.UIFigure.Visible = 'on';
            app.map = initialize_ACE2(mapFile);
            app.map_int = initialize_ACE_integer_ppf2(mapFile);
            fname = fullfile('GUI','map.mat');
            
            %save integer ppf map to supply this info to realtime app
            p = app.map_int;
            save(fname, 'p')
            
            %allow realtime communication
            app.Push2TalkButton.Enable = 'on';
            
            %updates level value on maps
            app.levelknob
        end

        % ButtonPlayTest button pushed function
        function runTrainningSentence(app)
            app.ButtonPlayTest.Enable = 'off';
            closeElectdgrmFigs(); %close previous electrodograms
            %% randomly choose trainning audio
            speechdir = fullfile('Sounds','Speech','Trainning');
            lst = subdirWavList(speechdir);
            idx = randi([1,length(lst)],1);
            speechFile = lst{idx};
            [app.FolderFullPath, app.fileName] = fileparts(speechFile);
            %% display sentence
            app.updtWordButtons();
            app.clearWordsButtons();
            %% Process
            app.StatusdereproduoLamp.Color = 'blue'; % update status
            plotflag = 1;
            app.stimuli = mod_stimulateGT( speechFile , app.map, plotflag );
            
            % minimize electrodogram figures
            pauseTime = 3;
            minElectdgrmFigs(pauseTime);
                
            %% Stream
            app.StatusdereproduoLamp.Color = 'red'; % update status
            Stream_2(app.stimuli);
            app.StatusdereproduoLamp.Color = 'green'; % update status
            
            app.ButtonPlayTest.Enable = 'on';
        end

        % Push2TalkButton value changed function
        function RT_TalkFcn(app)
            value = app.Push2TalkButton.Value;
            if value == 1
                setappdata(0,'map',app.map_int);
                hf=RealtimeStimulatorUFSC;
                while(isvalid(hf)) %wait while the RT stimulation is on
                    pause(1)
                end
                fname = fullfile('GUI','map.mat');
                z = load(fname); %loads the map changed on the realtime app
                app.map_int = z.p;
                clear z;
            end
            app.Push2TalkButton.Value = 0;
        end

        % TabGroup selection changed function
        function tabctrl(app)
            selectedTab = app.TabGroup.SelectedTab;
            
            switch app.tabctrlFlag
                case 0 % does not allow tab switch
                    app.TabGroup.SelectedTab = app.oldTab;
                case 1 % does not allow the qlt tab
                    if selectedTab == app.TabQlt
                        app.TabGroup.SelectedTab = app.oldTab;
                    end
                case 2 % does not allow the intel tab
                    if selectedTab == app.TabIntel
                        app.TabGroup.SelectedTab = app.oldTab;
                    end                    
            end
            app.oldTab = app.TabGroup.SelectedTab;
        end

        % CBUnp value changed function
        function cb(app)
            value = app.CBUnp.Value;
            app.CBUnp.Value = ~value;
        end

        % SRT29Unp value changed function
        function cb1(app)
            value = app.SRT29Unp.Value;
            app.SRT29Unp.Value = ~value;
        end

        % SRT50Unp value changed function
        function cb2(app)
            value = app.SRT50Unp.Value;
            app.SRT50Unp.Value = ~value;
        end

        % SRT71Unp value changed function
        function cb3(app)
            value = app.SRT71Unp.Value;
            app.SRT71Unp.Value = ~value;
        end

        % CBWie value changed function
        function cb4(app)
            value = app.CBWie.Value;
            app.CBWie.Value = ~value;
        end

        % SRT29Wie value changed function
        function cb5(app)
            value = app.SRT29Wie.Value;
            app.SRT29Wie.Value = ~value;
        end

        % SRT50Wie value changed function
        function cb6(app)
            value = app.SRT50Wie.Value;
            app.SRT50Wie.Value = ~value;
        end

        % SRT71Wie value changed function
        function cb7(app)
            value = app.SRT71Wie.Value;
            app.SRT71Wie.Value = ~value;
        end

        % CBEnv value changed function
        function cb8(app)
            value = app.CBEnv.Value;
            app.CBEnv.Value = ~value;
        end

        % SRT29Env value changed function
        function cb9(app)
            value = app.SRT29Env.Value;
            app.SRT29Env.Value = ~value;
        end

        % SRT50Env value changed function
        function cb10(app)
            value = app.SRT50Env.Value;
            app.SRT50Env.Value = ~value;
        end

        % SRT71Env value changed function
        function cb11(app)
            value = app.SRT71Env.Value;
            app.SRT71Env.Value = ~value;
        end

        % Value changed function
        function StartButtonValueChanged(app, event)
            %value = app.StartButton.Value;
            
            % enable Stop button
            app.StopButton.Enable = 'on';
            
            % clear word buttons
            app.clearWordsButtons();
                        
            % Create vector of results (considering that, during each list, will be presented 200 sentences)
            app.Results = zeros(20,9);
            app.NumTotalWords = zeros(20,9);
            app.snr_vecValues = zeros(20,9);
                        
            % Disable button
            app.StartButton.Enable = 'off';
            
            % Control SNR step (0 - 4dB / 1 - 2dB). Update based on app.correctRate variable
            app.SNRStep = 0;
            
            % Select first condition
            app.selectNR();
            app.selectSRT();
            
            % Initial SNR presented is 16dB
            app.valueSNR.Text = '16dB';
            app.snr_value = 16;
            
            % Run next sentence of the test
            app.runTestSentence();
            
            % enable buttons
            app.ComputeButton.Enable = 'on';
            app.repeatLastAudio.Enable = 'on';
            app.selectAll.Enable = 'on';
            app.selectNone.Enable = 'on';
            
            %indicates first audio is played, thus there is no difference in direction od SNR step
            app.diff_old = 0;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'CI Psychoacoustic Experiment';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeW);
            setAutoResize(app, app.UIFigure, true)
            app.DatarepresentationPanel = uipanel(app.UIFigure);
            app.DatarepresentationPanel.Title = 'Data representation';
            app.DatarepresentationPanel.Units = 'pixels';
            app.udioemexecuoTextAreaLabel = uilabel(app.DatarepresentationPanel);
            app.udioemexecuoTextAreaLabel.Text = 'Sentence ';
            app.fraseExecucao = uitextarea(app.DatarepresentationPanel);
            app.fraseExecucao.Editable = 'off';
            app.StatusdereproduoLampLabel = uilabel(app.DatarepresentationPanel);
            app.StatusdereproduoLampLabel.Text = 'Status:';
            app.StatusdereproduoLamp = uilamp(app.DatarepresentationPanel);
            app.VerdeudioexecutandoVermelhoOuvintepronunciandoAzulLabel = uilabel(app.DatarepresentationPanel);
            app.VerdeudioexecutandoVermelhoOuvintepronunciandoAzulLabel.Text = {'Green: Stand by'; 'Red:   Playing audio'; 'Blue:  Processing audio'};
            app.p1Button_1 = uibutton(app.DatarepresentationPanel, 'state');
            app.p1Button_1.Text = 'p1';
            app.p1Button_2 = uibutton(app.DatarepresentationPanel, 'state');
            app.p1Button_2.Text = 'p1';
            app.p1Button_3 = uibutton(app.DatarepresentationPanel, 'state');
            app.p1Button_3.Text = 'p1';
            app.p1Button_4 = uibutton(app.DatarepresentationPanel, 'state');
            app.p1Button_4.Text = 'p1';
            app.p1Button_5 = uibutton(app.DatarepresentationPanel, 'state');
            app.p1Button_5.Text = 'p1';
            app.p1Button_6 = uibutton(app.DatarepresentationPanel, 'state');
            app.p1Button_6.Text = 'p1';
            app.p1Button_7 = uibutton(app.DatarepresentationPanel, 'state');
            app.p1Button_7.Text = 'p1';
            app.p1Button_8 = uibutton(app.DatarepresentationPanel, 'state');
            app.p1Button_8.Text = 'p1';
            app.p1Button_9 = uibutton(app.DatarepresentationPanel, 'state');
            app.p1Button_9.Text = 'p1';
            app.Panel = uipanel(app.DatarepresentationPanel);
            app.Panel.Units = 'pixels';
            app.TotalwordscorrectLabel = uilabel(app.Panel);
            app.TotalwordscorrectLabel.Text = 'Correct words count';
            app.palavrasCorretas = uieditfield(app.Panel, 'numeric');
            app.palavrasCorretas.Editable = 'off';
            app.NmerototaldepalavrasEditFieldLabel = uilabel(app.Panel);
            app.NmerototaldepalavrasEditFieldLabel.Text = 'Total words';
            app.totalPalavras = uieditfield(app.Panel, 'numeric');
            app.totalPalavras.Editable = 'off';
            app.NextButton = uibutton(app.Panel, 'state');
            app.NextButton.ValueChangedFcn = createCallbackFcn(app, @NextButtonValueChanged);
            app.NextButton.Enable = 'off';
            app.NextButton.Text = 'Next';
            app.ComputeButton = uibutton(app.Panel, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed);
            app.ComputeButton.Enable = 'off';
            app.ComputeButton.Text = 'Compute';
            app.p1Button_10 = uibutton(app.DatarepresentationPanel, 'state');
            app.p1Button_10.Text = 'p1';
            app.p1Button_11 = uibutton(app.DatarepresentationPanel, 'state');
            app.p1Button_11.Text = 'p1';
            app.repeatLastAudio = uibutton(app.DatarepresentationPanel, 'state');
            app.repeatLastAudio.ValueChangedFcn = createCallbackFcn(app, @repeatLastAudioValueChanged);
            app.repeatLastAudio.Enable = 'off';
            app.repeatLastAudio.Text = 'Repeat';
            app.selectAll = uibutton(app.DatarepresentationPanel, 'push');
            app.selectAll.ButtonPushedFcn = createCallbackFcn(app, @funSelectAll);
            app.selectAll.Enable = 'off';
            app.selectAll.Text = 'All correct';
            app.selectNone = uibutton(app.DatarepresentationPanel, 'push');
            app.selectNone.ButtonPushedFcn = createCallbackFcn(app, @funSelectNone);
            app.selectNone.Enable = 'off';
            app.selectNone.Text = 'None correct';
            app.plotFlag = uicheckbox(app.DatarepresentationPanel);
            app.plotFlag.Enable = 'off';
            app.plotFlag.Text = 'Plot Electrodogram';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @tabctrl);
            app.TabGroup.Units = 'pixels';
            app.TabSetup = uitab(app.TabGroup);
            app.TabSetup.Units = 'pixels';
            app.TabSetup.Title = 'Set-up';
            app.PanelChecklist = uipanel(app.TabSetup);
            app.PanelChecklist.Title = 'Pre-test checklist';
            app.PanelChecklist.Units = 'pixels';
            app.checklistOKButton = uibutton(app.PanelChecklist, 'state');
            app.checklistOKButton.ValueChangedFcn = createCallbackFcn(app, @chcklstOK);
            app.checklistOKButton.Enable = 'off';
            app.checklistOKButton.Text = 'OK';
            app.CheckBoxTerm = uicheckbox(app.PanelChecklist);
            app.CheckBoxTerm.ValueChangedFcn = createCallbackFcn(app, @CBtermFcn);
            app.CheckBoxTerm.Text = 'Term of consent';
            app.CheckBoxExplain = uicheckbox(app.PanelChecklist);
            app.CheckBoxExplain.ValueChangedFcn = createCallbackFcn(app, @CBExplainFcn);
            app.CheckBoxExplain.Text = 'Explain the test';
            app.CheckBoxVolume = uicheckbox(app.PanelChecklist);
            app.CheckBoxVolume.ValueChangedFcn = createCallbackFcn(app, @CBVolFcn);
            app.CheckBoxVolume.Text = 'Adjust volume';
            app.CheckBoxLoad = uicheckbox(app.PanelChecklist);
            app.CheckBoxLoad.ValueChangedFcn = createCallbackFcn(app, @CBLoadMapFcn);
            app.CheckBoxLoad.Text = 'Load map';
            app.PanelGainControl = uipanel(app.TabSetup);
            app.PanelGainControl.Title = 'Gain Control';
            app.PanelGainControl.Units = 'pixels';
            app.LabelKnob = uilabel(app.PanelGainControl);
            app.LabelKnob.Enable = 'off';
            app.LabelKnob.Text = 'Master Gain [dB]';
            app.KnobGain = uiknob(app.PanelGainControl, 'continuous');
            app.KnobGain.Limits = [0 60];
            app.KnobGain.MajorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60];
            app.KnobGain.ValueChangedFcn = createCallbackFcn(app, @levelknob);
            app.KnobGain.Enable = 'off';
            app.KnobGain.Value = 10;
            app.ButtonPlayTest = uibutton(app.PanelGainControl, 'push');
            app.ButtonPlayTest.ButtonPushedFcn = createCallbackFcn(app, @runTrainningSentence);
            app.ButtonPlayTest.Enable = 'off';
            app.ButtonPlayTest.Text = 'Play next training audio';
            app.TabIntel = uitab(app.TabGroup);
            app.TabIntel.Units = 'pixels';
            app.TabIntel.Title = 'Intelligibility';
            app.ControlpanelPanel = uipanel(app.TabIntel);
            app.ControlpanelPanel.Title = 'Control panel';
            app.ControlpanelPanel.Units = 'pixels';
            app.StartButton = uibutton(app.ControlpanelPanel, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.ControlpanelPanel, 'state');
            app.StopButton.ValueChangedFcn = createCallbackFcn(app, @StopButtonValueChanged);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.AdaptativetestprogressPanel = uipanel(app.TabIntel);
            app.AdaptativetestprogressPanel.Title = 'Adaptative test progress';
            app.AdaptativetestprogressPanel.Units = 'pixels';
            app.SNRLabel = uilabel(app.AdaptativetestprogressPanel);
            app.SNRLabel.Text = 'SNR:';
            app.valueSNR = uilabel(app.AdaptativetestprogressPanel);
            app.valueSNR.Text = '------';
            app.AudiosentenceLabel = uilabel(app.AdaptativetestprogressPanel);
            app.AudiosentenceLabel.Text = 'Audio lists: ';
            app.sentence = uilabel(app.AdaptativetestprogressPanel);
            app.sentence.Text = '------';
            app.NumberAudio = uilabel(app.AdaptativetestprogressPanel);
            app.NumberAudio.Text = '------';
            app.audioLabel = uilabel(app.AdaptativetestprogressPanel);
            app.audioLabel.Text = 'audio # :';
            app.Label3 = uilabel(app.AdaptativetestprogressPanel);
            app.Label3.Text = 'Noise: ';
            app.Label4 = uilabel(app.AdaptativetestprogressPanel);
            app.Label4.Text = '4-talker babble (ISTS)';
            app.Label5 = uilabel(app.AdaptativetestprogressPanel);
            app.Label5.Text = '------';
            app.conditionsPanel = uipanel(app.AdaptativetestprogressPanel);
            app.conditionsPanel.Units = 'pixels';
            app.CBUnp = uicheckbox(app.conditionsPanel);
            app.CBUnp.ValueChangedFcn = createCallbackFcn(app, @cb);
            app.CBUnp.Enable = 'off';
            app.CBUnp.Text = 'Unproc.';
            app.SRT29Unp = uicheckbox(app.conditionsPanel);
            app.SRT29Unp.ValueChangedFcn = createCallbackFcn(app, @cb1);
            app.SRT29Unp.Enable = 'off';
            app.SRT29Unp.Text = 'SRT29';
            app.SRT50Unp = uicheckbox(app.conditionsPanel);
            app.SRT50Unp.ValueChangedFcn = createCallbackFcn(app, @cb2);
            app.SRT50Unp.Enable = 'off';
            app.SRT50Unp.Text = 'SRT50';
            app.SRT71Unp = uicheckbox(app.conditionsPanel);
            app.SRT71Unp.ValueChangedFcn = createCallbackFcn(app, @cb3);
            app.SRT71Unp.Enable = 'off';
            app.SRT71Unp.Text = 'SRT71';
            app.CBWie = uicheckbox(app.conditionsPanel);
            app.CBWie.ValueChangedFcn = createCallbackFcn(app, @cb4);
            app.CBWie.Enable = 'off';
            app.CBWie.Text = 'Wiener';
            app.SRT29Wie = uicheckbox(app.conditionsPanel);
            app.SRT29Wie.ValueChangedFcn = createCallbackFcn(app, @cb5);
            app.SRT29Wie.Enable = 'off';
            app.SRT29Wie.Text = 'SRT29';
            app.SRT50Wie = uicheckbox(app.conditionsPanel);
            app.SRT50Wie.ValueChangedFcn = createCallbackFcn(app, @cb6);
            app.SRT50Wie.Enable = 'off';
            app.SRT50Wie.Text = 'SRT50';
            app.SRT71Wie = uicheckbox(app.conditionsPanel);
            app.SRT71Wie.ValueChangedFcn = createCallbackFcn(app, @cb7);
            app.SRT71Wie.Enable = 'off';
            app.SRT71Wie.Text = 'SRT71';
            app.CBEnv = uicheckbox(app.conditionsPanel);
            app.CBEnv.ValueChangedFcn = createCallbackFcn(app, @cb8);
            app.CBEnv.Enable = 'off';
            app.CBEnv.Text = 'Env. Estim.';
            app.SRT29Env = uicheckbox(app.conditionsPanel);
            app.SRT29Env.ValueChangedFcn = createCallbackFcn(app, @cb9);
            app.SRT29Env.Enable = 'off';
            app.SRT29Env.Text = 'SRT29';
            app.SRT50Env = uicheckbox(app.conditionsPanel);
            app.SRT50Env.ValueChangedFcn = createCallbackFcn(app, @cb10);
            app.SRT50Env.Enable = 'off';
            app.SRT50Env.Text = 'SRT50';
            app.SRT71Env = uicheckbox(app.conditionsPanel);
            app.SRT71Env.ValueChangedFcn = createCallbackFcn(app, @cb11);
            app.SRT71Env.Enable = 'off';
            app.SRT71Env.Text = 'SRT71';
            app.TabQlt = uitab(app.TabGroup);
            app.TabQlt.Units = 'pixels';
            app.TabQlt.Title = 'Quality';
            app.StateButtonA = uibutton(app.TabQlt, 'state');
            app.StateButtonA.Text = 'A';
            app.StateButtonB = uibutton(app.TabQlt, 'state');
            app.StateButtonB.Text = 'B';
            app.LabelSlider = uilabel(app.TabQlt);
            app.LabelSlider.Text = 'Preference';
            app.Slider = uislider(app.TabQlt);
            app.Slider.Limits = [-1 1];
            app.Slider.MajorTicks = [-1 0 1];
            app.Slider.MajorTickLabels = {'Prefiro muito A', '0', 'Prefiro muito B'};
            app.Slider.MinorTicks = [];
            app.Button = uibutton(app.TabQlt, 'push');
            app.Button.Text = 'OK';
            app.Panel3 = uipanel(app.UIFigure);
            app.Panel3.Title = 'Live stream';
            app.Panel3.Units = 'pixels';
            app.Push2TalkButton = uibutton(app.Panel3, 'state');
            app.Push2TalkButton.ValueChangedFcn = createCallbackFcn(app, @RT_TalkFcn);
            app.Push2TalkButton.Enable = 'off';
            app.Push2TalkButton.Text = 'Push to talk';
        end
    end

    methods (Access = public)

        % Construct app
        function app = interface_expIC()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end