classdef interface_expIC_fixedSNR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure                   % CI Psy...
        DatarepresentationPanel matlab.ui.container.Panel          % Data r...
        udioemexecuoTextAreaLabel matlab.ui.control.Label % Sentence 
        fraseExecucao           matlab.ui.control.TextArea        
        StatusdereproduoLampLabel matlab.ui.control.Label % Status:
        StatusdereproduoLamp    matlab.ui.control.Lamp            
        VerdeudioexecutandoVermelhoOuvintepronunciandoAzulLabel matlab.ui.control.Label
        p1Button_1              matlab.ui.control.StateButton      % p1
        p1Button_2              matlab.ui.control.StateButton      % p1
        p1Button_3              matlab.ui.control.StateButton      % p1
        p1Button_4              matlab.ui.control.StateButton      % p1
        p1Button_5              matlab.ui.control.StateButton      % p1
        p1Button_6              matlab.ui.control.StateButton      % p1
        p1Button_7              matlab.ui.control.StateButton      % p1
        p1Button_8              matlab.ui.control.StateButton      % p1
        p1Button_9              matlab.ui.control.StateButton      % p1
        Panel                   matlab.ui.container.Panel         
        TotalwordscorrectLabel  matlab.ui.control.Label            % Correc...
        palavrasCorretas        matlab.ui.control.NumericEditField % [-Inf ...
        NmerototaldepalavrasEditFieldLabel matlab.ui.control.Label % Total ...
        totalPalavras           matlab.ui.control.NumericEditField % [-Inf ...
        NextButton              matlab.ui.control.StateButton      % Next
        ComputeButton           matlab.ui.control.Button           % Compute
        p1Button_10             matlab.ui.control.StateButton      % p1
        p1Button_11             matlab.ui.control.StateButton      % p1
        repeatLastAudio         matlab.ui.control.StateButton      % Repeat
        selectAll               matlab.ui.control.Button           % All co...
        selectNone              matlab.ui.control.Button           % None c...
        plotFlag                matlab.ui.control.CheckBox         % Plot E...
        TabGroup                matlab.ui.container.TabGroup       % Set-up...
        TabSetup                matlab.ui.container.Tab            % Set-up
        PanelChecklist          matlab.ui.container.Panel          % Pre-te...
        checklistOKButton       matlab.ui.control.StateButton      % OK
        CheckBoxTerm            matlab.ui.control.CheckBox         % Term o...
        CheckBoxExplain         matlab.ui.control.CheckBox         % Explai...
        CheckBoxVolume          matlab.ui.control.CheckBox         % Adjust...
        CheckBoxLoad            matlab.ui.control.CheckBox         % Load map
        PanelGainControl        matlab.ui.container.Panel          % Gain C...
        LabelKnob               matlab.ui.control.Label            % Master...
        KnobGain                matlab.ui.control.Knob             % [0 60]
        ButtonPlayTest          matlab.ui.control.Button           % Play n...
        TabIntel                matlab.ui.container.Tab            % Intell...
        ControlpanelPanel       matlab.ui.container.Panel          % Contro...
        StartButton             matlab.ui.control.StateButton      % Start
        StopButton              matlab.ui.control.StateButton      % Stop
        AdaptativetestprogressPanel matlab.ui.container.Panel % Adaptative ...
        conditionsPanel         matlab.ui.container.Panel         
        CBUnp                   matlab.ui.control.CheckBox         % Unproc.
        SRT29Unp                matlab.ui.control.CheckBox         % SRT29
        SRT50Unp                matlab.ui.control.CheckBox         % SRT50
        SRT71Unp                matlab.ui.control.CheckBox         % SRT71
        CBWie                   matlab.ui.control.CheckBox         % Wiener
        SRT29Wie                matlab.ui.control.CheckBox         % SRT29
        SRT50Wie                matlab.ui.control.CheckBox         % SRT50
        SRT71Wie                matlab.ui.control.CheckBox         % SRT71
        CBEnv                   matlab.ui.control.CheckBox         % Env. E...
        SRT29Env                matlab.ui.control.CheckBox         % SRT29
        SRT50Env                matlab.ui.control.CheckBox         % SRT50
        SRT71Env                matlab.ui.control.CheckBox         % SRT71
        LabelTextArea           matlab.ui.control.Label            % Lists
        ListDisp                matlab.ui.control.TextArea        
        LabelEditField          matlab.ui.control.Label            % Noise
        NoiseDisp               matlab.ui.control.EditField        % 4-talk...
        LabelNumericEditField   matlab.ui.control.Label            % SNR [dB]
        SNRdisp                 matlab.ui.control.NumericEditField % [-Inf ...
        LabelEditField2         matlab.ui.control.Label            % comput...
        AudioNumber             matlab.ui.control.EditField       
        Label                   matlab.ui.control.Label            % presen...
        presAudioNumber         matlab.ui.control.EditField       
        TabQlt                  matlab.ui.container.Tab            % Quality
        StateButtonA            matlab.ui.control.StateButton      % A
        StateButtonB            matlab.ui.control.StateButton      % B
        LabelSlider             matlab.ui.control.Label            % Prefer...
        Slider                  matlab.ui.control.Slider           % [-1 1]
        ButtonProx              matlab.ui.control.Button           % >> Pro...
        LabelEditField3         matlab.ui.control.Label            % Progresso
        qltTestProgress         matlab.ui.control.EditField        % 0 / 60
        StartButtonQlt          matlab.ui.control.Button           % Start
        Panel3                  matlab.ui.container.Panel          % Live s...
        Push2TalkButton         matlab.ui.control.StateButton      % Push t...
    end

    
    properties (Access = private)
        stimuli              % Description
        SNRStep              % Description
        snr_value               % Description
        earref                  % Description
        AZN                     % Description
        correctRate             % Description
        sentenceWords           % Description
        FolderFullPath          % Description
        fileName                % Description
        correctWords            % Description
        Results                 % Description
        NumTotalWords           % Description
        audioExec               % Description
        wordsAll                % Description
        snr_vecValues           % Description
        MF                      % Description
        folder1                 % Description
        audioN                  % Description
        previousAudio           % Description
        channelOutput           % Description
        flagVSameAudio          % Description
        flagAudioRepeated       % Description
        diff_old                % old derivative sign
        testedGains             % Description
        gainscount = 0;         % Description
        speechList              % list of speech files
        map                     % patient map
        map_int                 % patient map for real-time stimulation (integer ppf)
        oldTab                  % dummy used on tabctrl function
        scenario                % actual scenario
        adapstate               % state of the variables of the adaptative process
        usedLists               % already used lists
        NRlist                  % list of noise reduction methods
        SRTlist                 % list of SRT targets
        qltComb                 % combination of NR methods
        qltResults              % results of the quality experiment
        qltLists                % lists for the quality test
        stimuliB                % stimuli sequence for qlt test
    end
    
    properties (Access = public)
        tabctrlFlag = 0;            % flag to control tab behaviour (0: changes are not allowed)
    end
    
    methods (Access = private)
        
        function clearWordsButtons(app)
            % Clear sentence words buttons
            for iWord=1:11
                eval(sprintf('app.p1Button_%.0f.Visible = ''off'';',iWord))
                eval(sprintf('app.p1Button_%.0f.Value = 0;',iWord))
            end
        end
        
        
        function getNewAudio(app)
            % Randomly get new audio from Alcaim1_
            
            while(ismember(app.audioN,app.previousAudio))
                %%randomly pick a speech file
                L = length(app.speechList); %number of speech filesin the database
                idx = randi([1,L],1); %index of the randomly chosen file
                [fpath, fname] = fileparts(app.speechList{idx});
                app.audioN = fname;
                app.FolderFullPath = fpath;
            end            
            
            %store selected audio to history of played audios
            col = app.getcol();
            app.previousAudio{app.audioExec, col} = app.audioN;
            
            %returns fileName
            app.fileName = app.audioN;
       	    %%%% ----------------------------
        end
        
        function updtWordButtons(app)
            % zero selected words
            app.palavrasCorretas.Value = 0;
            %% clear word buttons
            app.clearWordsButtons();
            %% new words
            % Load metadata from audio
            fullFileName = fullfile(app.FolderFullPath,strcat(app.fileName,'.wav'));
            if ~exist(fullFileName, 'file')
                fullFileName(end-2:end) = 'WAV';   %if the .wav file does not exist, change extension to .WAV
            end
            
       	    % Le audio e metadata
            audioInformations = audioinfo(fullFileName);
            app.fraseExecucao.Value = audioInformations.Title;
            
            % Number of words per sentence
            app.sentenceWords = strsplit(audioInformations.Title);
            app.totalPalavras.Value = length(app.sentenceWords);
            
            % update words buttons
            col = app.getcol();
            for iWord=1:length(app.sentenceWords)
                app.wordsAll(app.audioExec,iWord,col) = app.sentenceWords(iWord);
                eval(sprintf('app.p1Button_%.0f.Text=app.sentenceWords{%.0f};',iWord,iWord))
                eval(sprintf('app.p1Button_%.0f.Visible = ''on'';',iWord))
            end    
        end
        
        function runTestSentence(app)
            closeElectdgrmFigs(); %close previous electrodograms
            %% store snr value
            col = app.getcol();
            app.snr_vecValues(app.audioExec,col) = app.snr_value;
            % Update SNR
            app.SNRdisp.Value = app.snr_value;
            %% Get audio from Alcaim1_ randomly
            app.getNewAudio();
            
            %% update words buttons
            app.updtWordButtons();
            
            %% Process audio
            app.StatusdereproduoLamp.Color = 'blue'; % update status
            
            noiseFileName = '4talker-babble_ISTS.wav';
            noisePath = fullfile('Sounds','Noise');
            noisefile = fullfile(noisePath,noiseFileName);
            
            % Load speech audio
            speechfile = fullfile(app.FolderFullPath,strcat(app.fileName,'.wav'));
            if ~exist(speechfile, 'file')
                speechfile(end-2:end) = 'WAV';   %if the .wav file does not exist, change extension to .WAV
            end
            
            % noise reduction
            % noise reduction technique: 'EnvEst', 'WF', 'BM','none'
            switch app.scenario.NR
                case 'EnvEstim'
                    tech = 'EnvEst';
                case 'Wiener'
                    tech = 'WF';
                case 'Binary Mask'
                    tech = 'BM';
                case 'Unprocessed'
                    tech = 'none';
            end
            
            app.stimuli = app.processAudio(speechfile, noisefile, tech); %process audio into stimuli sequence
            
            % minimize electrodogram figures
            pauseTime = 3;
            minElectdgrmFigs(pauseTime);
            
            app.StatusdereproduoLamp.Color = 'red'; % update status
            
            %% reproduce audio signal
            %
            try
                Stream_2(app.stimuli);
                app.presAudioNumber.Value = sprintf('%d / %d',...
                    app.audioExec, length(app.speechList));
                
            catch
                app.runTestSentence();
            end
            app.StatusdereproduoLamp.Color = 'green'; % update status
        end
        
        function clearTestFields(app)
            app.fraseExecucao.Value = '';
            app.SNRdisp.Value = 0;
            
            app.totalPalavras.Value = 0;
            app.palavrasCorretas.Value = 0;
        end
        
        function checklistitem(app)
            X = allchild(app.PanelChecklist); %get all children
            acc = 0; %number of boxes checked
            for ii = 1:length(X)
                if X(ii)~=app.checklistOKButton && X(ii).Value==1
                    acc = acc + 1;
                    X(ii).Enable = 'off';
                end
            end
            
            %if all boxes are checked, enable OK button
            if acc == length(X)-1;
                app.checklistOKButton.Enable = 'on';
            else
                app.checklistOKButton.Enable = 'off';
            end
        end
        
        function chooseSpeechLists(app)
            %randomly selects lists
            speechdir = fullfile('Sounds','Speech','Alcaim1_');
            Mlsts = subdirList(fullfile(speechdir,'M'));
            Flsts = subdirList(fullfile(speechdir,'F'));
            
            Mlsts = setdiff(Mlsts,app.usedLists);
            Flsts = setdiff(Flsts,app.usedLists);
            
            m = randi([1, length(Mlsts)]);
            f = randi([1, length(Flsts)]);
            
            lM = Mlsts{m};
            lF = Flsts{f};
            
            col = app.getcol();
            app.usedLists(:, col) = {lM;lF};
            %lists all wav file in the lists
            listF = subdirWavList(lF)';
            listM = subdirWavList(lM)';
            app.speechList = [listF;listM];
            
            %update panel with the chosen lists
            [~ , l1] = fileparts(lF);
            [~ , l2] = fileparts(lM);
            app.ListDisp.Value = {l1;l2};
        end
        
        function selectNR(app)
            off = 'off';
            on = 'on';
            
            if length(app.scenario.NR) > 2 ;
                NRabbr =  app.scenario.NR(1:3);
                cmd = sprintf('app.CB%s.Enable = off;', NRabbr);
                eval(cmd)
                if isnumeric(app.scenario.SRT)
                    NRabbr =  app.scenario.NR(1:3);
                    cmd = sprintf('app.SRT%d%s.Enable = off;', app.scenario.SRT, NRabbr);
                    eval(cmd)
                end
            end
            
            %select NR
            L = length(app.NRlist);
            ii = randi([1,L]);
            aux = app.NRlist{ii};
            app.scenario.NR = aux;
            
            NRabbr = aux(1:3);
            cmd = sprintf('app.CB%s.Enable = on;', NRabbr);
            eval(cmd)
            cmd = sprintf('app.CB%s.Value = 1;', NRabbr);
            eval(cmd)
            
            %update list
            app.NRlist = setdiff(app.NRlist,app.scenario.NR);
            app.set_SRTlist();            
        end
        
        function selectSRT(app)
            
            off = 'off';
            on = 'on';
            if length(app.scenario.NR) > 2 && isnumeric(app.scenario.SRT);
                NRabbr =  app.scenario.NR(1:3);
                cmd = sprintf('app.SRT%d%s.Enable = off;', app.scenario.SRT, NRabbr);
                eval(cmd)
            end
            %select SRT
            L = length(app.SRTlist);
            ii = randi([1,L]);
            aux = app.SRTlist(ii);
            app.scenario.SRT = aux;
            
            NRabbr = app.scenario.NR(1:3);
            cmd = sprintf('app.SRT%d%s.Enable = on;', app.scenario.SRT, NRabbr);
            eval(cmd)
            cmd = sprintf('app.SRT%d%s.Value = 1;', app.scenario.SRT, NRabbr);
            eval(cmd)
            
            %update list
            app.SRTlist = setdiff(app.SRTlist,app.scenario.SRT);
            
            %reset adapstate
            app.adapstate.amp = 4;   %amplitude of snr step is reseted to 4dB
            app.adapstate.stepflag = 0;
            app.adapstate.oldSignal = 0;
            app.adapstate.last = '';
        end
        
        function set_NRlist(app)
            app.NRlist = {'Unprocessed', 'Wiener', 'EnvEstim'};
        end
        
        function set_SRTlist(app)
            app.SRTlist = [29,50,71]';
        end
        
        function col = getcol(app)
            switch app.scenario.NR
                case 'Unprocessed'
                    a1 = 0;
                case 'Wiener'
                    a1 = 3;
                case 'EnvEstim'
                    a1 = 6;
            end
            switch app.scenario.SRT
                case 29
                    a2 = 0;
                case 50
                    a2 = 1;
                case 71
                    a2 = 2;
            end
            col = 1 + (a1 + a2);
        end
        
        function deltaSNR = calcSNRstep(app)
            %% select up and down groups (Levitt, 1971)
            switch app.scenario.SRT
                case 29
                    upSet = {'--'};
                    downSet = {'+','-+'};
                case 50
                    upSet = {'-'};
                    downSet = {'+'};
                case 71
                    upSet = {'-', '+-'};
                    downSet = {'++'};
            end
            
            %% latest outcome
            if app.correctRate>= .5
                outcome = '+';
            else
                outcome = '-';
            end
            
            dut = strcat(app.adapstate.last, outcome);
            
            %% chooses action
            if ismember(dut, upSet)
                sinal = +1;
                app.adapstate.last = '';
            elseif ismember(dut, downSet)
                sinal = -1;
                app.adapstate.last = '';
            else
                sinal = 0;
                app.adapstate.last = dut;
            end
            
            %% check amplitude
            if ~app.adapstate.stepflag
                if sinal~=0
                    if(app.adapstate.oldSignal + sinal == 0)
                        app.adapstate.stepflag = 1;
                        app.adapstate.amp = 2;
                    else
                        app.adapstate.oldSignal = sinal;
                    end
                end                
            end
            %% output
            deltaSNR = sinal*app.adapstate.amp;
        end
        
        function newConditions(app)
            app.selectSRT();
            app.chooseSpeechLists();            
            
            % Initial SNR presented is 16dB
            app.snr_value = 16;
            app.SNRdisp.Value = app.snr_value;
            
            app.audioExec = 1; %start variable
            app.AudioNumber.Value ='';
            app.presAudioNumber.Value = '';
        end

        function [lin,col] = selectQltScenario(app)
            %% check which scenarios were not yet tested
            aux = isnan(app.qltResults);
            index = find(aux);
            if ~isempty(index) % if there are scenarios to be tested
                %randomly picks one
                ii = datasample(index,1);
                [lin, col] = ind2sub(size(aux), ii);
            else %returns NaN
                lin = NaN;
                col = NaN;
            end
        end

        function loadQltScenario(app)
            [lin,col] = app.selectQltScenario(); %get indexes. lin is related to the sentence; col to the NRs under test
            if isnan(lin)
                %% save results
                disp('Save results')
                
                Qresultados.comb = app.qltComb;
                Qresultados.resultados = app.qltResults;
                Qresultados.Lists = app.qltLists;
                
                olddir = pwd;
                cd('Results');
                pname = app.map.General.SubjectName;
                fname = strcat(pname,'_QLT.mat');
                [savefilename,savepathname] = uiputfile(fname,'Save results as');
                cd(olddir)
                save(fullfile(savepathname,savefilename),'Qresultados')
                return; %if there are no more scenarios to be tested the function returns.
            end
            NRs = app.qltComb(col,:);
            NR_A = NRs(randi([1,2]));
            NR_B = setdiff(NRs,NR_A);
            
            if strcmp(NR_A,NRs(1));
                app.adapstate.qltInvAB = 1;
            else
                app.adapstate.qltInvAB = -1;
            end
            
            %speech
            if lin>length(app.qltLists)
                lin2 = lin - length(app.qltLists);
            else
                lin2 = lin;
            end
            speechfile = app.qltLists{lin2, col};
            
            %noise
            noiseFileName = '4talker-babble_ISTS.wav';
            noisePath = fullfile('Sounds','Noise');
            noisefile = fullfile(noisePath,noiseFileName);
            
            %% Button A
            switch(NR_A{1})
                case 'EnvEstim'
                    tech = 'EnvEst';
                case 'Wiener'
                    tech = 'WF';
                case 'Binary Mask'
                    tech = 'BM';
                case 'Unprocessed'
                    tech = 'none';
            end
            app.stimuli = processAudio(app, speechfile, noisefile, tech);
            %% Button B
            switch(NR_B{1})
                case 'EnvEstim'
                    tech = 'EnvEst';
                case 'Wiener'
                    tech = 'WF';
                case 'Binary Mask'
                    tech = 'BM';
                case 'Unprocessed'
                    tech = 'none';
            end
            app.stimuliB = processAudio(app, speechfile, noisefile, tech);
            app.adapstate.qltrefs = [lin, col]; %index for loaded scenario
            
            %% Enable controls
            app.Slider.Enable = 'on';
            app.StateButtonA.Enable = 'on';
            app.StateButtonB.Enable = 'on';
            
            %update progress display
            [nrows, ncols] = size(app.qltResults);
            total = nrows*ncols;
            n = sum(isnan(app.qltResults(:)));
            txt = sprintf('%d / %d', total-n+1, total);
            app.qltTestProgress.Value = txt;
        end

        function stim = processAudio(app, speechfile, noisefile, tech)
            if isempty(app.snr_value) || isnan(app.snr_value)
                app.snr_value = 16;
            end
            sgnls = SignalY(speechfile, noisefile, app.snr_value);
            y = sgnls.Smix;
            x = sgnls.target;
            v = sgnls.noise;            
            
            %% stimulate
            stim = mod_stimulateGT_NR(y,x,v, app.map, tech, app.plotFlag.Value); %use gammatone filters            
        end        

        function [lin, col] = selectIntScenario(app)
            %% check which scenarios were not yet tested
            aux = isnan(app.Results);
            index = find(aux);
            if ~isempty(index) % if there are scenarios to be tested
                %randomly picks one
                ii = datasample(index,1);
                [lin, col] = ind2sub(size(aux), ii);
            else %returns NaN
                lin = NaN;
                col = NaN;
            end           
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            close all
            app.UIFigure.Resize = 'off';
            %movegui(app.UIFigure,'center'); %induces error on versions 2016b+
            
            %go to CCIMod dir
            [~,current] = fileparts(pwd);
            while ~strcmpi(current,'CCIMod')
                cd ..;
                [~,current] = fileparts(pwd);
            end
            init; %initialize path
            
            %clear word buttons
            app.clearWordsButtons();
            
            %initialize variables
            app.scenario.NR = '';
            app.scenario.SRT = '';
            app.adapstate.last = '';
            app.adapstate.amp = 4;
            app.adapstate.stepflag = 0;
            app.adapstate.oldSignal = 0;
            
            app.usedLists = cell(2,9);
            app.usedLists(:) = {''};
            
            app.set_NRlist();
            app.set_SRTlist();
            
            %
            app.correctWords = cell(20,11,9); %(# of audio files, words, scenario)
            app.wordsAll = cell(20,11,9);
            app.audioN = '';
            app.previousAudio = cell(20,9); % Used to control which audio files have been played
            app.previousAudio(:) = {''};
            
            % tab management
            app.tabctrlFlag = 0;
            app.oldTab = app.TabGroup.SelectedTab;
            
            %start log file
            fn = strcat(datestr(now,'ddmmmyy_HH.MM'),'.log');    %file name
            diary(fullfile('Logs',fn));
            
            % java obsolete warning turned off
            id = 'MATLAB:HandleGraphics:ObsoletedProperty:JavaFrame';
            warning('off',id)
        end

        % Button pushed function
        function ComputeButtonPushed(app, event)
            % Indicate numbers of audios executed (max = 20)
            app.AudioNumber.Value = sprintf('%d',app.audioExec);
            
            %% calculate number of correct words
            cumCorr = 0;
            col = app.getcol();
            for iWord=1:length(app.sentenceWords)
                eval(sprintf('value = app.p1Button_%.0f.Value;',iWord))
                eval(sprintf('palavra = app.p1Button_%.0f.Text;',iWord))
                if value == 1
                    cumCorr = cumCorr + 1;
                    app.correctWords{app.audioExec,iWord,col} = palavra;
                end
            end
            
            %% store data
            col = app.getcol();
            
            app.palavrasCorretas.Value = cumCorr;
            app.Results(app.audioExec, col) = app.palavrasCorretas.Value;
            app.NumTotalWords(app.audioExec, col) = app.totalPalavras.Value;
            %app.snr_vecValues(app.audioExec) = app.snr_value;
            
            %% enable Next button
            app.NextButton.Enable = 'on';
            app.NextButton.Value = 0;
            
            %% compute ratio of correct words, then update variables app.correctRate and app.snr_value
            app.correctRate = app.palavrasCorretas.Value/app.totalPalavras.Value;
            
        end

        % Value changed function
        function NextButtonValueChanged(app, event)
            %value = app.NextButton.Value;
            app.audioExec = app.audioExec + 1;
            
            %% update variables based on the last correctRatio
            deltaSNR = app.calcSNRstep();
            app.snr_value = app.snr_value + deltaSNR;
            
            %store actual SNR value
            col = app.getcol();
            app.snr_vecValues(app.audioExec,col) = app.snr_value;
            
            %% test if all theaudio files were played
            if app.audioExec > length(app.speechList);
                if isempty(app.NRlist) && isempty(app.SRTlist) %if all NR were tested
                    %save data
                    % disable buttons
                    app.ComputeButton.Enable = 'off';
                    app.NextButton.Enable = 'off';
                    
                    % save all variables
                    disp('Save results')
                    
                    resultados.numTotalPalavras = app.NumTotalWords;
                    resultados.numAcertos = app.Results;
                    resultados.palCorretas = app.correctWords;
                    resultados.wordsAll = app.wordsAll;
                    resultados.snr_vecValues = app.snr_vecValues;
                    resultados.usedLists = app.usedLists;
                    
                    olddir = pwd;
                    cd('Results');
                    pname = app.map.General.SubjectName;
                    fname = strcat(pname,'_INT.mat');
                    saveflag = 1;
                    while(saveflag)
                        try
                            [savefilename,savepathname] = uiputfile(fname,'Save results as');
                            cd(olddir)
                            save(fullfile(savepathname,savefilename),'resultados')
                            saveflag = 0;
                        catch
                        end
                    end
                    app.clearWordsButtons();
                    
                    % clear fields
                    clearTestFields(app)
                    
                    %allow quality test tab
                    app.tabctrlFlag = 2;
                    return;
                elseif isempty(app.SRTlist) % if not, select another NR
                    app.selectNR();
                end
                %select new SRT and initialize variables
                app.newConditions();    
            end
            
            %%
            
            % Disable buttons
            app.ComputeButton.Enable = 'off';
            app.NextButton.Enable = 'off';
            
            % Run next sentence of the test
            app.runTestSentence();
            app.ComputeButton.Enable = 'on';
        end

        % Value changed function
        function repeatLastAudioValueChanged(app, event)
            value = app.repeatLastAudio.Value;
            %disable button
            app.repeatLastAudio.Enable = 'off';
            
            % play audio
            app.StatusdereproduoLamp.Color = 'Red';
            Stream_2(app.stimuli);
            app.StatusdereproduoLamp.Color = 'Green';
            
            % Enable Repeat button
            app.repeatLastAudio.Value = 0;
            app.repeatLastAudio.Enable = 'on';
            
        end

        % Value changed function
        function StopButtonValueChanged(app, event)
            %open dialog box to check if the user really wants to stop
            msg = {'Are you sure you want to stop the experiment?',...
                'All data will be lost'};
            choice = questdlg(msg, 'Stop experiment', ...
                'Yes, quit','No, return to the test', ...
                'No, return to the test');
            if choice(1) == 'N'
                app.StopButton.Value = 0;
            else
                app.closeW();
            end
        end

        % Value changed function
        function chcklstOK(app, event)
            
            app.plotFlag.Enable = 'on';
            app.totalPalavras.Value = 0;
            app.fraseExecucao.Value = '';
            app.ButtonPlayTest.Enable = 'off';
            app.KnobGain.Enable = 'off';
            
            app.tabctrlFlag = 1; %allow int tab
            app.TabGroup.SelectedTab = app.TabIntel;
            app.oldTab = app.TabIntel;
            
            app.checklistOKButton.Value = 0;
            app.checklistOKButton.Enable = 'off';
        end

        % Value changed function
        function CBtermFcn(app, event)
            %value = app.CheckBoxTerm.Value;
            app.checklistitem();
        end

        % Value changed function
        function CBExplainFcn(app, event)
            %value = app.CheckBoxExplain.Value;
            app.checklistitem();
        end

        % Value changed function
        function CBVolFcn(app, event)
            %value = app.CheckBoxVolume.Value;
            app.checklistitem();
            app.KnobGain.Enable = 'on';
            app.ButtonPlayTest.Enable = 'on';
        end

        % Value changed function
        function CBScenFcn(app, event)
            value = app.CheckBoxScenario.Value;
            app.checklistitem();
            app.KnobGain.Enable = 'off';
            app.ButtonPlayTest.Enable = 'off';
        end

        % Close request function
        function closeW(app, event)
            diary off;
            fname = fullfile('GUI','map.mat');
            if exist(fname, 'file')
                delete(fname);
            end
            app.delete;
        end

        % Button pushed function
        function funSelectAll(app, event)
            % select all word buttons
            for iWord=1:length(app.sentenceWords)
                eval(sprintf('app.p1Button_%.0f.Value=1;',iWord));
            end
        end

        % Button pushed function
        function funSelectNone(app, event)
            % unselect all word buttons
            for iWord=1:length(app.sentenceWords)
                eval(sprintf('app.p1Button_%.0f.Value=0;',iWord));
            end
        end

        % Value changed function
        function levelknob(app, event)
            %Controls the gain of the left
            value = app.KnobGain.Value;
            if (isfield(app.map,'Left') == 1)
                app.map.Left.Gain = value;
                app.map.Left.gains_dB = app.map.Left.Gain + app.map.Left.BandGains;
                app.map.Left.gains = 10 .^ (app.map.Left.gains_dB / 20.0);
                
                app.map_int.Left.Gain = value;
                app.map_int.Left.gains_dB = app.map.Left.Gain + app.map.Left.BandGains;
                app.map_int.Left.gains = 10 .^ (app.map.Left.gains_dB / 20.0);
            end
            if (isfield(app.map,'Right') == 1)
                app.map.Right.Gain = value;
                app.map.Right.gains_dB = app.map.Right.Gain + app.map.Right.BandGains;
                app.map.Right.gains = 10 .^ (app.map.Right.gains_dB / 20.0);
                
                app.map_int.Right.Gain = value;
                app.map_int.Right.gains_dB = app.map.Right.Gain + app.map.Right.BandGains;
                app.map_int.Right.gains = 10 .^ (app.map.Right.gains_dB / 20.0);
            end
        end

        % Value changed function
        function CBLoadMapFcn(app, event)
            %value = app.CheckBoxLoad.Value;
            app.checklistitem();
            %load patient map and starts the board
            app.UIFigure.Visible = 'off';
            mapFile = chooseMAP();
            app.UIFigure.Visible = 'on';
            app.map = initialize_ACE2(mapFile);
            app.map_int = initialize_ACE_integer_ppf2(mapFile);
            fname = fullfile('GUI','map.mat');
            
            %save integer ppf map to supply this info to realtime app
            p = app.map_int;
            save(fname, 'p')
            
            %allow realtime communication
            app.Push2TalkButton.Enable = 'on';
            
            %updates level value on maps
            app.levelknob
        end

        % Button pushed function
        function runTrainningSentence(app, event)
            app.ButtonPlayTest.Enable = 'off';
            closeElectdgrmFigs(); %close previous electrodograms
            %% randomly choose trainning audio
            speechdir = fullfile('Sounds','Speech','Trainning');
            lst = subdirWavList(speechdir);
            idx = randi([1,length(lst)],1);
            speechFile = lst{idx};
            [app.FolderFullPath, app.fileName] = fileparts(speechFile);
            %% display sentence
            % Load metadata from audio
            fullFileName = fullfile(app.FolderFullPath,strcat(app.fileName,'.wav'));
            if ~exist(fullFileName, 'file')
                fullFileName(end-2:end) = 'WAV';   %if the .wav file does not exist, change extension to .WAV
            end
            
       	    % Le audio e metadata
            audioInformations = audioinfo(fullFileName);
            app.fraseExecucao.Value = audioInformations.Title;
            %% Process
            app.StatusdereproduoLamp.Color = 'blue'; % update status
            plotflag = 1;
            app.stimuli = mod_stimulateGT( speechFile , app.map, plotflag );
            
            % minimize electrodogram figures
            pauseTime = 3;
            minElectdgrmFigs(pauseTime);
            
            %% Stream
            app.StatusdereproduoLamp.Color = 'red'; % update status
            Stream_2(app.stimuli);
            app.StatusdereproduoLamp.Color = 'green'; % update status
            
            app.ButtonPlayTest.Enable = 'on';
        end

        % Value changed function
        function RT_TalkFcn(app, event)
            value = app.Push2TalkButton.Value;
            if value == 1
                setappdata(0,'map',app.map_int);
                hf=RealtimeStimulatorUFSC;
                while(isvalid(hf)) %wait while the RT stimulation is on
                    pause(1)
                end
                fname = fullfile('GUI','map.mat');
                z = load(fname); %loads the map changed on the realtime app
                app.map_int = z.p;
                clear z;
            end
            app.Push2TalkButton.Value = 0;
        end

        % Value changed function
        function cb(app, event)
            value = app.CBUnp.Value;
            app.CBUnp.Value = ~value;
        end

        % Value changed function
        function cb1(app, event)
            value = app.SRT29Unp.Value;
            app.SRT29Unp.Value = ~value;
        end

        % Value changed function
        function cb2(app, event)
            value = app.SRT50Unp.Value;
            app.SRT50Unp.Value = ~value;
        end

        % Value changed function
        function cb3(app, event)
            value = app.SRT71Unp.Value;
            app.SRT71Unp.Value = ~value;
        end

        % Value changed function
        function cb4(app, event)
            value = app.CBWie.Value;
            app.CBWie.Value = ~value;
        end

        % Value changed function
        function cb5(app, event)
            value = app.SRT29Wie.Value;
            app.SRT29Wie.Value = ~value;
        end

        % Value changed function
        function cb6(app, event)
            value = app.SRT50Wie.Value;
            app.SRT50Wie.Value = ~value;
        end

        % Value changed function
        function cb7(app, event)
            value = app.SRT71Wie.Value;
            app.SRT71Wie.Value = ~value;
        end

        % Value changed function
        function cb8(app, event)
            value = app.CBEnv.Value;
            app.CBEnv.Value = ~value;
        end

        % Value changed function
        function cb9(app, event)
            value = app.SRT29Env.Value;
            app.SRT29Env.Value = ~value;
        end

        % Value changed function
        function cb10(app, event)
            value = app.SRT50Env.Value;
            app.SRT50Env.Value = ~value;
        end

        % Value changed function
        function cb11(app, event)
            value = app.SRT71Env.Value;
            app.SRT71Env.Value = ~value;
        end

        % Value changed function
        function StartButtonValueChanged(app, event)
            %value = app.StartButton.Value;            
            % enable Stop button
            app.StopButton.Enable = 'on';
            
            % clear word buttons
            app.clearWordsButtons();
            
            % Create vector of results
            app.Results = NaN*ones(10,18); % each group of 6 columns reffer to Unp, Wiener and EnvFilter respectively
            app.NumTotalWords = NaN*ones(10,18); % the columns in each group are divided in 6 SNR groups
            app.snr_vecValues = NaN*ones(10,18);
            
            % sort lists
            app.chooseSpeechLists();
            
            % Disable button
            app.StartButton.Enable = 'off';
            
            % Select first condition
            app.selectIntScenario();
            
            % Run next sentence of the test
            app.runTestSentence();
            
            % enable buttons
            app.ComputeButton.Enable = 'on';
            %app.repeatLastAudio.Enable = 'on';
            app.selectAll.Enable = 'on';
            app.selectNone.Enable = 'on';
            
            %indicates first audio is played, thus there is no difference in direction od SNR step
            app.diff_old = 0;
        end

        % Button pushed function
        function func_StartButtonQlt(app, event)
            app.StartButtonQlt.Enable = 'off';
            %% startup
            app.set_NRlist();
            app.qltComb = nchoosek(app.NRlist,2);
            app.qltResults = NaN*ones(2*10,length(app.qltComb)); %10 frases por lista (Maristela Costa)
            lists = datasample(1:7,length(app.qltComb),'Replace',false); %chooses N out of 7 lists from Maristela Costa
            path = fullfile('Sounds', 'Speech', 'MCosta');
            listNames = sprintf(',''Lista %dB''',lists);
            aux = eval(strcat('{',listNames(2:end),'}'));
            listFullName = fullfile(path,aux)';
            app.qltLists = cell(10,length(app.qltComb));
            for ii = 1:length(app.qltComb);
                app.qltLists(:,ii) = subdirWavList(listFullName{ii})';
            end
            %% enable progress display
            app.qltTestProgress.Enable = 'on';
            %% determine SNR for qlt test
            SRT50 = calcSRTx(app.snr_vecValues(:,2));
            app.snr_value = median(SRT50);
            %% load scenario
            app.loadQltScenario();
            
            app.adapstate.AOK = 0;
            app.adapstate.BOK = 0;
        end

        % Value changed function
        function func_ButtonA(app, event)
            value = app.StateButtonA.Value;
            %% disable buttons
                        app.StateButtonA.Enable = 'off';
            app.StateButtonB.Enable = 'off';
            %% Stream stimuli A
            % play audio
            app.StatusdereproduoLamp.Color = 'Red';
            Stream_2(app.stimuli);
            app.StatusdereproduoLamp.Color = 'Green';
            %% enable buttons
            app.StateButtonA.Enable = 'on';
            app.StateButtonB.Enable = 'on';
            app.StateButtonA.Value = not(value);
            
            app.adapstate.AOK = 1;
            if app.adapstate.BOK == 1;
                app.ButtonProx.Enable = 'on';
            end
        end

        % Value changed function
        function func_ButtonB(app, event)
            value = app.StateButtonB.Value;
            %% disable buttons
            app.StateButtonA.Enable = 'off';
            app.StateButtonB.Enable = 'off';
            %% Stream stimuli A
            % play audio
            app.StatusdereproduoLamp.Color = 'Red';
            Stream_2(app.stimuliB);
            app.StatusdereproduoLamp.Color = 'Green';
            %% enable buttons
            app.StateButtonA.Enable = 'on';
            app.StateButtonB.Enable = 'on';
            app.StateButtonB.Value = not(value);
            
            app.adapstate.BOK = 1;
            if app.adapstate.AOK == 1;
                app.ButtonProx.Enable = 'on';
            end
        end

        % Button pushed function
        function func_ButtonProx(app, event)
            %% disable buttons
            app.ButtonProx.Enable = 'off';
            app.StateButtonA.Enable = 'off';
            app.StateButtonB.Enable = 'off';
            app.Slider.Enable = 'off';
            %%
            RCs = app.adapstate.qltrefs;
            app.qltResults(RCs(1), RCs(2)) = app.Slider.Value*app.adapstate.qltInvAB;
            %% load scenario
            app.loadQltScenario();
            %% enable buttons
            app.StateButtonA.Enable = 'on';
            app.StateButtonB.Enable = 'on';
            app.Slider.Enable = 'on';
            app.Slider.Value = 0;
            %%
            app.adapstate.AOK = 0;
            app.adapstate.BOK = 0;
        end

        % Selection change function
        function tabctrl(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            app.TabGroup.SelectedTab = app.oldTab;
            
            switch app.tabctrlFlag
                case 0 % does not allow tab switch
                    
                case 1 % does not allow the qlt tab
                    if selectedTab  ~= app.TabQlt
                        app.TabGroup.SelectedTab = selectedTab;
                    end
                case 2 % does not allow the intel tab
                    if selectedTab ~= app.TabIntel
                        app.TabGroup.SelectedTab = selectedTab;
                    end
                otherwise %allows any change
                    app.TabGroup.SelectedTab = selectedTab;
            end
            app.oldTab = app.TabGroup.SelectedTab;            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'CI Psychoacoustic Experiment';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeW);
            setAutoResize(app, app.UIFigure, true)
            app.DatarepresentationPanel = uipanel(app.UIFigure);
            app.DatarepresentationPanel.Title = 'Data representation';
            app.DatarepresentationPanel.Units = 'pixels';
            app.udioemexecuoTextAreaLabel = uilabel(app.DatarepresentationPanel);
            app.udioemexecuoTextAreaLabel.Text = 'Sentence ';
            app.fraseExecucao = uitextarea(app.DatarepresentationPanel);
            app.fraseExecucao.Editable = 'off';
            app.StatusdereproduoLampLabel = uilabel(app.DatarepresentationPanel);
            app.StatusdereproduoLampLabel.Text = 'Status:';
            app.StatusdereproduoLamp = uilamp(app.DatarepresentationPanel);
            app.VerdeudioexecutandoVermelhoOuvintepronunciandoAzulLabel = uilabel(app.DatarepresentationPanel);
            app.VerdeudioexecutandoVermelhoOuvintepronunciandoAzulLabel.Text = {'Green: Stand by'; 'Red:   Playing audio'; 'Blue:  Processing audio'};
            app.p1Button_1 = uibutton(app.DatarepresentationPanel, 'state');
            app.p1Button_1.Text = 'p1';
            app.p1Button_2 = uibutton(app.DatarepresentationPanel, 'state');
            app.p1Button_2.Text = 'p1';
            app.p1Button_3 = uibutton(app.DatarepresentationPanel, 'state');
            app.p1Button_3.Text = 'p1';
            app.p1Button_4 = uibutton(app.DatarepresentationPanel, 'state');
            app.p1Button_4.Text = 'p1';
            app.p1Button_5 = uibutton(app.DatarepresentationPanel, 'state');
            app.p1Button_5.Text = 'p1';
            app.p1Button_6 = uibutton(app.DatarepresentationPanel, 'state');
            app.p1Button_6.Text = 'p1';
            app.p1Button_7 = uibutton(app.DatarepresentationPanel, 'state');
            app.p1Button_7.Text = 'p1';
            app.p1Button_8 = uibutton(app.DatarepresentationPanel, 'state');
            app.p1Button_8.Text = 'p1';
            app.p1Button_9 = uibutton(app.DatarepresentationPanel, 'state');
            app.p1Button_9.Text = 'p1';
            app.Panel = uipanel(app.DatarepresentationPanel);
            app.Panel.Units = 'pixels';
            app.TotalwordscorrectLabel = uilabel(app.Panel);
            app.TotalwordscorrectLabel.Text = 'Correct words count';
            app.palavrasCorretas = uieditfield(app.Panel, 'numeric');
            app.palavrasCorretas.Editable = 'off';
            app.NmerototaldepalavrasEditFieldLabel = uilabel(app.Panel);
            app.NmerototaldepalavrasEditFieldLabel.Text = 'Total words';
            app.totalPalavras = uieditfield(app.Panel, 'numeric');
            app.totalPalavras.Editable = 'off';
            app.NextButton = uibutton(app.Panel, 'state');
            app.NextButton.ValueChangedFcn = createCallbackFcn(app, @NextButtonValueChanged);
            app.NextButton.Enable = 'off';
            app.NextButton.Text = 'Next';
            app.ComputeButton = uibutton(app.Panel, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed);
            app.ComputeButton.Enable = 'off';
            app.ComputeButton.Text = 'Compute';
            app.p1Button_10 = uibutton(app.DatarepresentationPanel, 'state');
            app.p1Button_10.Text = 'p1';
            app.p1Button_11 = uibutton(app.DatarepresentationPanel, 'state');
            app.p1Button_11.Text = 'p1';
            app.repeatLastAudio = uibutton(app.DatarepresentationPanel, 'state');
            app.repeatLastAudio.ValueChangedFcn = createCallbackFcn(app, @repeatLastAudioValueChanged);
            app.repeatLastAudio.Enable = 'off';
            app.repeatLastAudio.Text = 'Repeat';
            app.selectAll = uibutton(app.DatarepresentationPanel, 'push');
            app.selectAll.ButtonPushedFcn = createCallbackFcn(app, @funSelectAll);
            app.selectAll.Enable = 'off';
            app.selectAll.Text = 'All correct';
            app.selectNone = uibutton(app.DatarepresentationPanel, 'push');
            app.selectNone.ButtonPushedFcn = createCallbackFcn(app, @funSelectNone);
            app.selectNone.Enable = 'off';
            app.selectNone.Text = 'None correct';
            app.plotFlag = uicheckbox(app.DatarepresentationPanel);
            app.plotFlag.Enable = 'off';
            app.plotFlag.Text = 'Plot Electrodogram';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.Units = 'pixels';
            app.TabSetup = uitab(app.TabGroup);
            app.TabSetup.Units = 'pixels';
            app.TabSetup.Title = 'Set-up';
            app.PanelChecklist = uipanel(app.TabSetup);
            app.PanelChecklist.Title = 'Pre-test checklist';
            app.PanelChecklist.Units = 'pixels';
            app.checklistOKButton = uibutton(app.PanelChecklist, 'state');
            app.checklistOKButton.ValueChangedFcn = createCallbackFcn(app, @chcklstOK);
            app.checklistOKButton.Enable = 'off';
            app.checklistOKButton.Text = 'OK';
            app.CheckBoxTerm = uicheckbox(app.PanelChecklist);
            app.CheckBoxTerm.ValueChangedFcn = createCallbackFcn(app, @CBtermFcn);
            app.CheckBoxTerm.Text = 'Term of consent';
            app.CheckBoxExplain = uicheckbox(app.PanelChecklist);
            app.CheckBoxExplain.ValueChangedFcn = createCallbackFcn(app, @CBExplainFcn);
            app.CheckBoxExplain.Text = 'Explain the test';
            app.CheckBoxVolume = uicheckbox(app.PanelChecklist);
            app.CheckBoxVolume.ValueChangedFcn = createCallbackFcn(app, @CBVolFcn);
            app.CheckBoxVolume.Text = 'Adjust volume';
            app.CheckBoxLoad = uicheckbox(app.PanelChecklist);
            app.CheckBoxLoad.ValueChangedFcn = createCallbackFcn(app, @CBLoadMapFcn);
            app.CheckBoxLoad.Text = 'Load map';
            app.PanelGainControl = uipanel(app.TabSetup);
            app.PanelGainControl.Title = 'Gain Control';
            app.PanelGainControl.Units = 'pixels';
            app.LabelKnob = uilabel(app.PanelGainControl);
            app.LabelKnob.Enable = 'off';
            app.LabelKnob.Text = 'Master Gain [dB]';
            app.KnobGain = uiknob(app.PanelGainControl, 'continuous');
            app.KnobGain.Limits = [0 60];
            app.KnobGain.MajorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60];
            app.KnobGain.ValueChangedFcn = createCallbackFcn(app, @levelknob);
            app.KnobGain.Enable = 'off';
            app.KnobGain.Value = 10;
            app.ButtonPlayTest = uibutton(app.PanelGainControl, 'push');
            app.ButtonPlayTest.ButtonPushedFcn = createCallbackFcn(app, @runTrainningSentence);
            app.ButtonPlayTest.Enable = 'off';
            app.ButtonPlayTest.Text = 'Play next training audio';
            app.TabIntel = uitab(app.TabGroup);
            app.TabIntel.Units = 'pixels';
            app.TabIntel.Title = 'Intelligibility';
            app.ControlpanelPanel = uipanel(app.TabIntel);
            app.ControlpanelPanel.Title = 'Control panel';
            app.ControlpanelPanel.Units = 'pixels';
            app.StartButton = uibutton(app.ControlpanelPanel, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.ControlpanelPanel, 'state');
            app.StopButton.ValueChangedFcn = createCallbackFcn(app, @StopButtonValueChanged);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.AdaptativetestprogressPanel = uipanel(app.TabIntel);
            app.AdaptativetestprogressPanel.Title = 'Adaptative test progress';
            app.AdaptativetestprogressPanel.Units = 'pixels';
            app.conditionsPanel = uipanel(app.AdaptativetestprogressPanel);
            app.conditionsPanel.Units = 'pixels';
            app.CBUnp = uicheckbox(app.conditionsPanel);
            app.CBUnp.ValueChangedFcn = createCallbackFcn(app, @cb);
            app.CBUnp.Enable = 'off';
            app.CBUnp.Text = 'Unproc.';
            app.SRT29Unp = uicheckbox(app.conditionsPanel);
            app.SRT29Unp.ValueChangedFcn = createCallbackFcn(app, @cb1);
            app.SRT29Unp.Enable = 'off';
            app.SRT29Unp.Text = 'SRT29';
            app.SRT50Unp = uicheckbox(app.conditionsPanel);
            app.SRT50Unp.ValueChangedFcn = createCallbackFcn(app, @cb2);
            app.SRT50Unp.Enable = 'off';
            app.SRT50Unp.Text = 'SRT50';
            app.SRT71Unp = uicheckbox(app.conditionsPanel);
            app.SRT71Unp.ValueChangedFcn = createCallbackFcn(app, @cb3);
            app.SRT71Unp.Enable = 'off';
            app.SRT71Unp.Text = 'SRT71';
            app.CBWie = uicheckbox(app.conditionsPanel);
            app.CBWie.ValueChangedFcn = createCallbackFcn(app, @cb4);
            app.CBWie.Enable = 'off';
            app.CBWie.Text = 'Wiener';
            app.SRT29Wie = uicheckbox(app.conditionsPanel);
            app.SRT29Wie.ValueChangedFcn = createCallbackFcn(app, @cb5);
            app.SRT29Wie.Enable = 'off';
            app.SRT29Wie.Text = 'SRT29';
            app.SRT50Wie = uicheckbox(app.conditionsPanel);
            app.SRT50Wie.ValueChangedFcn = createCallbackFcn(app, @cb6);
            app.SRT50Wie.Enable = 'off';
            app.SRT50Wie.Text = 'SRT50';
            app.SRT71Wie = uicheckbox(app.conditionsPanel);
            app.SRT71Wie.ValueChangedFcn = createCallbackFcn(app, @cb7);
            app.SRT71Wie.Enable = 'off';
            app.SRT71Wie.Text = 'SRT71';
            app.CBEnv = uicheckbox(app.conditionsPanel);
            app.CBEnv.ValueChangedFcn = createCallbackFcn(app, @cb8);
            app.CBEnv.Enable = 'off';
            app.CBEnv.Text = 'Env. Estim.';
            app.SRT29Env = uicheckbox(app.conditionsPanel);
            app.SRT29Env.ValueChangedFcn = createCallbackFcn(app, @cb9);
            app.SRT29Env.Enable = 'off';
            app.SRT29Env.Text = 'SRT29';
            app.SRT50Env = uicheckbox(app.conditionsPanel);
            app.SRT50Env.ValueChangedFcn = createCallbackFcn(app, @cb10);
            app.SRT50Env.Enable = 'off';
            app.SRT50Env.Text = 'SRT50';
            app.SRT71Env = uicheckbox(app.conditionsPanel);
            app.SRT71Env.ValueChangedFcn = createCallbackFcn(app, @cb11);
            app.SRT71Env.Enable = 'off';
            app.SRT71Env.Text = 'SRT71';
            app.LabelTextArea = uilabel(app.AdaptativetestprogressPanel);
            app.LabelTextArea.Text = 'Lists';
            app.ListDisp = uitextarea(app.AdaptativetestprogressPanel);
            app.ListDisp.Editable = 'off';
            app.LabelEditField = uilabel(app.AdaptativetestprogressPanel);
            app.LabelEditField.Text = 'Noise';
            app.NoiseDisp = uieditfield(app.AdaptativetestprogressPanel, 'text');
            app.NoiseDisp.Editable = 'off';
            app.NoiseDisp.Value = '4-talker babble (ISTS)';
            app.LabelNumericEditField = uilabel(app.AdaptativetestprogressPanel);
            app.LabelNumericEditField.Text = 'SNR [dB]';
            app.SNRdisp = uieditfield(app.AdaptativetestprogressPanel, 'numeric');
            app.SNRdisp.ValueDisplayFormat = '%+d';
            app.SNRdisp.Editable = 'off';
            app.LabelEditField2 = uilabel(app.AdaptativetestprogressPanel);
            app.LabelEditField2.Text = 'computed Audio #';
            app.AudioNumber = uieditfield(app.AdaptativetestprogressPanel, 'text');
            app.AudioNumber.Editable = 'off';
            app.Label = uilabel(app.AdaptativetestprogressPanel);
            app.Label.Text = 'presented Audio #';
            app.presAudioNumber = uieditfield(app.AdaptativetestprogressPanel, 'text');
            app.presAudioNumber.Editable = 'off';
            app.TabQlt = uitab(app.TabGroup);
            app.TabQlt.Units = 'pixels';
            app.TabQlt.Title = 'Quality';
            app.StateButtonA = uibutton(app.TabQlt, 'state');
            app.StateButtonA.ValueChangedFcn = createCallbackFcn(app, @func_ButtonA);
            app.StateButtonA.Enable = 'off';
            app.StateButtonA.Text = 'A';
            app.StateButtonB = uibutton(app.TabQlt, 'state');
            app.StateButtonB.ValueChangedFcn = createCallbackFcn(app, @func_ButtonB);
            app.StateButtonB.Enable = 'off';
            app.StateButtonB.Text = 'B';
            app.LabelSlider = uilabel(app.TabQlt);
            app.LabelSlider.Text = 'Preference';
            app.Slider = uislider(app.TabQlt);
            app.Slider.Limits = [-1 1];
            app.Slider.MajorTicks = [-1 0 1];
            app.Slider.MajorTickLabels = {'Prefiro muito A', '0', 'Prefiro muito B'};
            app.Slider.MinorTicks = [];
            app.Slider.Enable = 'off';
            app.ButtonProx = uibutton(app.TabQlt, 'push');
            app.ButtonProx.ButtonPushedFcn = createCallbackFcn(app, @func_ButtonProx);
            app.ButtonProx.Enable = 'off';
            app.ButtonProx.Text = '>> Proxima frase >>';
            app.LabelEditField3 = uilabel(app.TabQlt);
            app.LabelEditField3.Enable = 'off';
            app.LabelEditField3.Text = 'Progresso';
            app.qltTestProgress = uieditfield(app.TabQlt, 'text');
            app.qltTestProgress.Enable = 'off';
            app.qltTestProgress.Editable = 'off';
            app.qltTestProgress.Value = '0 / 60';
            app.StartButtonQlt = uibutton(app.TabQlt, 'push');
            app.StartButtonQlt.ButtonPushedFcn = createCallbackFcn(app, @func_StartButtonQlt);
            app.StartButtonQlt.Text = 'Start';
            app.Panel3 = uipanel(app.UIFigure);
            app.Panel3.Title = 'Live stream';
            app.Panel3.Units = 'pixels';
            app.Push2TalkButton = uibutton(app.Panel3, 'state');
            app.Push2TalkButton.ValueChangedFcn = createCallbackFcn(app, @RT_TalkFcn);
            app.Push2TalkButton.Enable = 'off';
            app.Push2TalkButton.Text = 'Push to talk';
        end
    end

    methods (Access = public)

        % Construct app
        function app = interface_expIC_fixedSNR()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end